classdef MRS4BRAIN_toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MenuTab                         matlab.ui.container.Tab
        CIBM_logo_Image                 matlab.ui.control.Image
        Welcome_Label                   matlab.ui.control.Label
        App_logo_Image                  matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        MRSI_Label                      matlab.ui.control.Label
        MRSI_Button                     matlab.ui.control.Button
        SVS_Label                       matlab.ui.control.Label
        SVS_Button                      matlab.ui.control.Button
        DWS_Label                       matlab.ui.control.Label
        DWS_Button                      matlab.ui.control.Button
        MRSITab                         matlab.ui.container.Tab
        MRSI_Display_results_Panel      matlab.ui.container.Panel
        MRSI_Show_Results_Label         matlab.ui.control.Label
        MRSI_Show_Results_Panel         matlab.ui.container.Panel
        MRSI_Job0CheckBox               matlab.ui.control.CheckBox
        MRSI_Display_additional_Button  matlab.ui.control.Button
        MRSI_Overlap_EditField          matlab.ui.control.NumericEditField
        MRSI_CustomButton               matlab.ui.control.Button
        MRSI_ConcentrationsDropDown     matlab.ui.control.DropDown
        ConcLabel                       matlab.ui.control.Label
        MRSI_CustomregionsCheckBox      matlab.ui.control.CheckBox
        OverlapSliderLabel              matlab.ui.control.Label
        MRSI_RegionDropDown             matlab.ui.control.DropDown
        MRSI_SideDropDown               matlab.ui.control.DropDown
        SideDropDownLabel               matlab.ui.control.Label
        MRSI_Voxel_selection_Button     matlab.ui.control.Button
        MRSI_MetaboliteDropDown         matlab.ui.control.DropDown
        MRSI_Slice_DropDown             matlab.ui.control.DropDown
        MRSI_conc_table_Button          matlab.ui.control.Button
        MRSI_OverlapSlider              matlab.ui.control.Slider
        MRSI_Save_map_Button            matlab.ui.control.Button
        MRSI_Spectra_Button             matlab.ui.control.Button
        MRSI_Volumetry_Button           matlab.ui.control.Button
        SliceDropDownLabel              matlab.ui.control.Label
        MetaboliteDropDownLabel         matlab.ui.control.Label
        RegionDropDownLabel             matlab.ui.control.Label
        UIAxes_MRSI_2                   matlab.ui.control.UIAxes
        UIAxes_MRSI_1                   matlab.ui.control.UIAxes
        MRSI_Materials_Panel            matlab.ui.container.Panel
        MRSI_OptionsLabel               matlab.ui.control.Label
        MRSI_Options_Panel              matlab.ui.container.Panel
        MRSI_Statistics_Button          matlab.ui.control.Button
        MRSI_Preferences_Button         matlab.ui.control.Button
        MRSI_Data_specific_Label        matlab.ui.control.Label
        MRSI_Data_specific_Panel        matlab.ui.container.Panel
        MRI_HeadProne_Box               matlab.ui.control.CheckBox
        ReferenceLabel_3                matlab.ui.control.Label
        MRSI_Open_pdfdoc_Button         matlab.ui.control.Button
        MRSI_RecoNb_EditField           matlab.ui.control.NumericEditField
        RecoNbLabel                     matlab.ui.control.Label
        MRSI_Ref_CheckBox               matlab.ui.control.CheckBox
        SlicerangeLabel                 matlab.ui.control.Label
        MRSI_exp_nb_folder_Label        matlab.ui.control.Label
        ReferenceLabel                  matlab.ui.control.Label
        MRSI_Met_EditField              matlab.ui.control.NumericEditField
        MetabolitesEditFieldLabel       matlab.ui.control.Label
        MRSI_Ref_EditField              matlab.ui.control.NumericEditField
        MRSI_Image_EditField            matlab.ui.control.NumericEditField
        ImageEditFieldLabel             matlab.ui.control.Label
        MRSI_Slice_range_EditField      matlab.ui.control.NumericEditField
        MRSI_MRISlice_UITable           matlab.ui.control.Table
        MRSI_Analysis_Button            matlab.ui.control.Button
        MRSI_Processing_steps_Label     matlab.ui.control.Label
        MRSI_Processings_Panel          matlab.ui.container.Panel
        MRSI_3D_CheckBox                matlab.ui.control.CheckBox
        ReferenceLabel_2                matlab.ui.control.Label
        ReconstructionDropDownLabel     matlab.ui.control.Label
        MRSI_Number_slices_Spinner      matlab.ui.control.Spinner
        MRSI_LipidsupButton             matlab.ui.control.StateButton
        MRSI_FillgapsButton             matlab.ui.control.StateButton
        MRSI_Denoising_DropDown         matlab.ui.control.DropDown
        MRSI_Reconstruction_DropDown    matlab.ui.control.DropDown
        DenoisingDropDownLabel          matlab.ui.control.Label
        NumberslicesSpinnerLabel        matlab.ui.control.Label
        MRSI_Data_management_Label      matlab.ui.control.Label
        MRSI_Data_management_Panel      matlab.ui.container.Panel
        MRSI_Choose_path_Button         matlab.ui.control.Button
        MRSI_result_folder_Button       matlab.ui.control.Button
        MRSI_Studyname_Button           matlab.ui.control.Button
        MRSI_Studyname_EditField        matlab.ui.control.EditField
        StudynameEditFieldLabel         matlab.ui.control.Label
        Toolbox_Image_MRSI              matlab.ui.control.Image
        CIBM_Image_MRSI                 matlab.ui.control.Image
        SVSTab                          matlab.ui.container.Tab
        Display_results_Panel_SVS       matlab.ui.container.Panel
        SVS_Previous_spectrum_Button    matlab.ui.control.Button
        SVS_Next_spectrum_Button        matlab.ui.control.Button
        SVS_Spectrum_nb_EditField       matlab.ui.control.NumericEditField
        SVS_ExperimentDropDown          matlab.ui.control.DropDown
        SVS_TypeDropDown                matlab.ui.control.DropDown
        SVS_absButton                   matlab.ui.control.StateButton
        SVS_realButton                  matlab.ui.control.StateButton
        SVS_imagButton                  matlab.ui.control.StateButton
        SVS_DataButton                  matlab.ui.control.StateButton
        SVS_FitButton                   matlab.ui.control.StateButton
        SVS_BaselineButton              matlab.ui.control.StateButton
        UITable_SVS_results             matlab.ui.control.Table
        TypeDropDownLabel               matlab.ui.control.Label
        ExperimentDropDownLabel         matlab.ui.control.Label
        UIAxes_SVS                      matlab.ui.control.UIAxes
        Materials_Panel_SVS             matlab.ui.container.Panel
        SVSParametersButton             matlab.ui.control.Button
        CIBM_Image_SVS                  matlab.ui.control.Image
        Toolbox_Image_SVS               matlab.ui.control.Image
        SVS_Data_management_Label       matlab.ui.control.Label
        SVS_Data_management_Panel       matlab.ui.container.Panel
        Load_SVS_data_Button            matlab.ui.control.Button
        SVS_result_folder_Button        matlab.ui.control.Button
        SVS_Studyname_Button            matlab.ui.control.Button
        SVS_StudynameEditField          matlab.ui.control.EditField
        StudynameEditField_2Label       matlab.ui.control.Label
        SVS_Experiments_Label           matlab.ui.control.Label
        SVS_Experiments_Panel           matlab.ui.container.Panel
        SVS_Metabolite_data_Label       matlab.ui.control.Label
        Ref_data_SVS_DropDown           matlab.ui.control.DropDown
        UITable_met_SVS                 matlab.ui.control.Table
        SVS_Select_all_Button           matlab.ui.control.Button
        SVS_Deselect_all_Button         matlab.ui.control.Button
        SVS_Choose_selection_Button     matlab.ui.control.Button
        ReferencedataLabel              matlab.ui.control.Label
        SVS_Data_Processing_Button      matlab.ui.control.Button
        SVS_Quantification_Button       matlab.ui.control.Button
        DWSTab                          matlab.ui.container.Tab
        DWS_Display_results_Pane        matlab.ui.container.Panel
        DWS_spectra_savefigure_Button   matlab.ui.control.Button
        DWS_fit_savefigure_Button       matlab.ui.control.Button
        DWS_ResultsDropDown             matlab.ui.control.DropDown
        ResultsDropDownLabel            matlab.ui.control.Label
        DWS_Previous_spectrum_Button    matlab.ui.control.Button
        DWS_Next_spectrum_Button        matlab.ui.control.Button
        DWS_Spectrum_nb_EditField       matlab.ui.control.NumericEditField
        DWS_absButton                   matlab.ui.control.StateButton
        DWS_realButton                  matlab.ui.control.StateButton
        DWS_imagButton                  matlab.ui.control.StateButton
        DWS_DataButton                  matlab.ui.control.StateButton
        DWS_FitButton                   matlab.ui.control.StateButton
        DWS_BaselineButton              matlab.ui.control.StateButton
        DWS_fitting_Panel               matlab.ui.container.Panel
        DWS_update_fitting_Button       matlab.ui.control.Button
        DWS_rad_kurtosis_EditField      matlab.ui.control.NumericEditField
        RadiusKurtosisEditFieldLabel    matlab.ui.control.Label
        DWS_exclude_bvalue_EditField    matlab.ui.control.NumericEditField
        ExcludedbvalueEditFieldLabel    matlab.ui.control.Label
        DWS_Metabolite_fit_DropDown     matlab.ui.control.DropDown
        MetaboliteDropDown_2Label       matlab.ui.control.Label
        AKC_EditField                   matlab.ui.control.NumericEditField
        AKCLabel                        matlab.ui.control.Label
        ADC_EditField                   matlab.ui.control.NumericEditField
        ADCLabel                        matlab.ui.control.Label
        Da_fit_EditField                matlab.ui.control.NumericEditField
        D_intraLabel                    matlab.ui.control.Label
        UITable_DWS_results             matlab.ui.control.Table
        DWS_TypeDropDown                matlab.ui.control.DropDown
        TypeDropDownLabel_2             matlab.ui.control.Label
        DWS_ExperimentDropDown          matlab.ui.control.DropDown
        ExperimentDropDownLabel_2       matlab.ui.control.Label
        UIAxes_DWS_fitting              matlab.ui.control.UIAxes
        UIAxes_DWS                      matlab.ui.control.UIAxes
        DWS_Materials_Panel             matlab.ui.container.Panel
        DWS_ParametersButton            matlab.ui.control.Button
        DWS_Experiments_Label           matlab.ui.control.Label
        CIBM_Image_DWS                  matlab.ui.control.Image
        Toolbox_Image_DWS               matlab.ui.control.Image
        DWS_Data_management_Label       matlab.ui.control.Label
        DWS_Data_management_Panel       matlab.ui.container.Panel
        DWS_Load_data_Button            matlab.ui.control.Button
        DWS_result_folder_Button        matlab.ui.control.Button
        DWS_Studyname_Button            matlab.ui.control.Button
        DWS_StudynameEditField          matlab.ui.control.EditField
        StudynameEditField_2Label_2     matlab.ui.control.Label
        DWS_Experiments_Panel           matlab.ui.container.Panel
        DWS_Metabolite_data_Label       matlab.ui.control.Label
        UITable_met_DWS                 matlab.ui.control.Table
        DWS_Select_all_Button           matlab.ui.control.Button
        DWS_Deselect_all_Button         matlab.ui.control.Button
        DWS_Choose_selection_Button     matlab.ui.control.Button
        Ref_data_DWS_DropDown           matlab.ui.control.DropDown
        ReferencedataLabel_2            matlab.ui.control.Label
        DWS_Data_Processing_Button      matlab.ui.control.Button
        DWS_Quantification_Button       matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Dialog windows
        PreferencesApp              % Link to the preferences App
        ParametersSVSDWSApp         % Link to the parameters SVS/DWS App
        VoxelSelectionApp           % Link to the Voxel selection App
        ConcTableApp                % Link to the Concentration table App
        VolumetryTableApp           % Link to the Volumetry table App
        SpectraplotApp
        CustomRegionsApp            % Link to the Custom Regions App
        DisplayOptionsApp           % Link to the Display options App
        SVSDWSLoadApp               % Link to the SVS/DWS Loading App
        % MRSI properties
        metab_fn                    % Metabolite fid folder number
        ref_fn                      % Reference fid folder number
        image_fn                    % NIfTI image folder name
        reco_fn                     % Reconstruction number
        slice_range                 % Range of slices studied over and under the central slice
        N_slices                    % Number of studied slices
        Slices_number               % Position of the slices
        folder_dir = ''             % Directory of the studied folder
        denoising = 'None'          % Denoising statut
        max_folder_number = 99      % Maximum number of folder in the Brucker data folder
        overlap = 0.8               % Minimal overlap between the MRI segmentation and the MRSI voxel
        image_filename              % Filename of the studied image
        MRIReg_element              % Class object with all the Image processings
        MRSI_experiment             % Class object with all the Signal/Spectroscopic imaging processings
        clrbar                      % Colorbar on the side of the GUI
        max_steps = -1              % Maximum number of steps reached in the MRS analysis
        region_name                 % Name of the studied region from registration
        mrsi3d_bool                 % 3D MRSI activated or not
        % SVS properties
        SVS_experiment
        SVS_type_study
        SVS_max_spectra
        SVS_quantif_bool = 0
        % DWS properties
        DWS_experiment
        DWS_type_study
        DWS_max_spectra
        DWS_quantif_bool = 0
    end
    
    properties (Access = public)
        % Overall properties
        app_path                    % Path of the Matlab app
        result_dir = ''             % Directory of the results folder
        study_name = ''             % Name of the study
        Spec_calc                   % Current spectroscopy calculus
        % MRSI properties
        mrsi_param                  % MRSI parameters
        reg_param                   % MRI registration parameters
        disp_opt                    % Display preferences
        MRSI_done_bool = false      % MRSI processing/analysis finished
        mri_headprone               % MRI Head Prone indicator
        MRSI_mean_SNR = 0           % Mean SNR for MRSI
        avg_SNR = 0                 % Mean SNR CALCULATED MANUALLY
        MRSI_mean_FWHM = 0          % Mean FWHM for MRSI
        avg_Lw = 0                  % Mean Linewidth CALCULATED MANUALLY
        current_mask                % Current mask to overlay the metabolite maps
        current_metab_map           % Current displayed metabolite map without interpolation
        max_clrb                    % Maximal value of the colorbar
        original_region = 1         % 1: regions segmented with the MRI process, 0: modified with the voxel selection programm
        t_custom_regions            % Table with the studied regions and their sides
        % SVS properties
        struct_svs_exp              % SVS experiments struct
        svs_param                   % SVS preprocessing and quantification
        % DWS properties
        struct_dws_exp              % DWS experiments struct
        dws_param                   % DWS preprocessing and quantification
    end
    
    methods (Access = private)
        
        function init_app(app)
            % Functions to initialize the directories of the data and
            % results with previous values used in the GUI
            temp = load(fullfile(app.app_path,'Others','Cookies.mat'),'T');
            T = temp.T;
            if isfolder(T.data_folder)
                app.folder_dir = T.data_folder;
            end
            if isfolder(T.results_folder)
                app.result_dir = T.results_folder;
            end
        end

        function init_table(app)
            % Function to initialize the UITable with the folder numbers
            % and the position of the central slice in the MRSI slab
            app.MRSI_MRISlice_UITable.Data = [{'MRI central slice 1'},{8}];
            app.image_fn = 12;
            app.metab_fn = 20;
            app.ref_fn = 21;
            app.reco_fn = 1;
            app.slice_range = 1;
            app.N_slices = 1;
            app.Slices_number = 8;
        end
        
        function MRSI_check_directory(app)
            % Function to check the number of folders in the chosen Bruker
            % data folder
            sub_folders = dir(app.folder_dir);
            max_num = 99;
            for i = 1:length(sub_folders)
                f_name = str2double(sub_folders(i).name);
                if ~isempty(f_name)
                    if f_name > max_num
                        max_num = f_name;
                    end
                end
            end
            app.max_folder_number = max_num;
        end
        
        %% MRI_Registration do the image registration and segmentation for the MRSI data
        function ok = MRI_Registration(app)
            ok = 1; % output initialization
            % Check if prexisting folder for the segmentation with the
            % following files
            image_files = {};
            image_reg_folder = fullfile(app.folder_dir,'registration');
            image_files{1} = fullfile(image_reg_folder,'image_study_xG.nii.gz');
            image_files{2} = fullfile(image_reg_folder,'image_study_xG_r.nii.gz');
            image_files{3} = fullfile(image_reg_folder,'brain_mask.nii.gz');
            image_files{4} = fullfile(image_reg_folder,'affine_transformation.mat');
            image_files{5} = fullfile(image_reg_folder,'space_transformation.nii.gz');
            image_files{6} = fullfile(image_reg_folder,'brain_labels.nii.gz');
            if ~isfolder(fullfile(app.folder_dir,'registration'))
                redo_segmentation = 1;
            elseif not(all(isfile(image_files)))
                redo_segmentation = 1;
            else
                msg = 'Do you want to redo the image registration or keep the prexisting one ?';
                selection = uiconfirm(app.UIFigure,msg,'Image processing', ...
                    'Options',{'Redo registration','Keep the prexisting'},'DefaultOption',2,'CancelOption',2);
                switch selection
                    case 'Redo registration'
                        % Redo the registration and segmentation
                        redo_segmentation = 1;
                    case 'Keep the prexisting'
                        redo_segmentation = 0;
                end
            end

            if redo_segmentation
                nifti_image_dir = fullfile(app.folder_dir,num2str(app.image_fn), ...
                    'pdata','1','nifti');
                if ~isfolder(nifti_image_dir)
                    uialert(app.UIFigure, ...
                        {['No NIfTI image in folder ' num2str(app.image_fn) ' !'], ...
                        'Please choose another folder'},'Image processing','Icon','error');
                    ok = 0;
                    return
                end
                file = dir(fullfile(nifti_image_dir, '*.nii'));
                if length(file) > 1
                    uialert(app.UIFigure,{'More than 1 NIfTI image in the image folder', ...
                        'The first image has been taken'},...
                        'Image processing','Icon','warning')
                    file = file(1);
                end
                original_image_fn = fullfile(file.folder,file.name);
                app.MRIReg_element = MRIRegClass(original_image_fn,app.reg_param, ...
                    app.folder_dir,fullfile(app.result_dir,app.study_name),app.mri_headprone); % MRIReg Class constructor

                d = uiprogressdlg(app.UIFigure,'Title','Image processing', ...
                    'Message','Registering the image with the template (may take a while)', ...
                    'Indeterminate','on','Cancelable','on');
                
                % Image registration
                msg = app.MRIReg_element.image_registration(d);
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Image processing')
                    ok = 0;
                    return
                end

                % Image segmentation
                d.Message = 'Segmentation of the image';
                msg = app.MRIReg_element.image_segmentation();
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Image processing')
                    ok = 0;
                    return
                end

                % Labels translation
                d.Message = 'Translating the label';
                msg = app.MRIReg_element.labels_translation();
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Image processing')
                    ok = 0;
                    return
                end
            else
                app.image_filename = fullfile(app.folder_dir,'registration','image_study_xG.nii.gz');
                app.MRIReg_element = MRIRegClass(app.image_filename,app.reg_param,...
                    app.folder_dir,fullfile(app.result_dir,app.study_name),app.mri_headprone); % MRIReg class constructor

                % Brain mask calculation
                msg = app.MRIReg_element.brain_mask();
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Image processing')
                    ok = 0;
                    return
                end

                % Labels translation
                msg = app.MRIReg_element.labels_translation();
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Image processing')
                    ok = 0;
                    return
                end
            end

            % Add the regions in the RegionDropDown
            app.MRSI_RegionDropDown.Enable = 'on';
            str_data = app.MRIReg_element.Segmentation;
            if ~ischar(str_data(1).Name)
                app.MRSI_RegionDropDown.Items = [[str_data.Name],'Whole Brain'];
            else
                app.MRSI_RegionDropDown.Items = [convertCharsToStrings({str_data.Name}),'Whole Brain'];
            end

            % Create custom regions table
            Regions = {app.MRIReg_element.Segmentation.Name}.';
            State = false(length(Regions),1);
            [C_side{1:length(Regions),1}] = deal('L+R');
            Sides = categorical(C_side,{'Left','Right','L+R'});
            app.t_custom_regions = table(State,Regions,Sides,'VariableNames',{'On/Off','Brain region','Side'});

            % Plot the result on the axis
            min_slice = app.Slices_number(1) - app.slice_range; % minimum slice in the range
            max_slice = app.Slices_number(1) + app.slice_range; % maximum slice in the range
            slice = rot90(squeeze(sum(double(app.MRIReg_element.original_image(:,:,min_slice:max_slice)),3)),-1);
            slice = uint8(slice/max(max(slice))*256);
            imshow(slice,'Parent',app.UIAxes_MRSI_1)
            axis(app.UIAxes_MRSI_1,'equal')
            assignin("base",'MRI',app.MRIReg_element)
        end

        
        %% MRSI_analysis do the spectroscopic imaging processing for the MRSI data
        function ok = MRSI_analysis(app)
            ok = 1; % Output initialization (check if the process has been done without error)
            % MRS element intitialization
            redo_MRSI_init = 1;
            if app.max_steps >= 0
                selection = uiconfirm(app.UIFigure,['Would you like to restart' ...
                    ' the spectroscopy processing ?'],'Spectroscopic imaging processing', ...
                    'Options',{'Yes','No'},'DefaultOption',2);
                if strcmp(selection,'No')
                    redo_MRSI_init = 0;
                    processed = 0;
                else
                    redo_MRSI_init = 1;
                end
            end

            d = uiprogressdlg(app.UIFigure,"Title",'MR Spectroscopy imaging processing ...',...
                'Message','Reading the data and checking prexisting results','Value',0,'Cancelable','on');
            max_processings = 11;
            pause(0.5)

            if redo_MRSI_init
                app.MRSI_experiment = MRSIClass(app.folder_dir,app.metab_fn,app.ref_fn,app.reco_fn,...
                    app.mrsi_param,app.slice_range,app.Slices_number,app.mrsi3d_bool); % Class constructor
                app.MRSI_experiment.results_folder = fullfile(app.result_dir,app.study_name);
                % Reading the data
                % TO BE MODIFIED FOR NON CARTESIAN RECONSTRUCTION
                msg = app.MRSI_experiment.read_data();
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Spectroscopic imaging processing')
                    ok = 0;
                    return
                end
                app.max_steps = -1;
                % Check if the data have already been proceded
                [msg,processed] = app.MRSI_experiment.check_prexisting_results(app.MRIReg_element.Brain_mask);
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Spectroscopic imaging processing')
                    ok = 0;
                    return
                end
            end

            if processed > 0
                % Process already done on a previous occasion, check if wanted
                % to be done again
                if processed == 1
                    % Data quantified
                    selection = uiconfirm(app.UIFigure,{'Data have already been processed.' ...
                        'Would you like to reprocess the data ?'},'Spectroscopic imaging processing', ...
                        'Options',{'Yes','No'},'DefaultOption',2);
                    if strcmp(selection,'Yes')
                        app.max_steps = -1;
                    else
                        app.max_steps = 8;
                    end
                else
                    % Data not quantified
                    selection = uiconfirm(app.UIFigure,{'Data have been saved in RAW files but not quantified.' ...
                        'Would you like to quantify them ?'},'Spectroscopic imaging processing', ...
                        'Options',{'Quantify','Reprocess'},'DefaultOption',1);
                    if strcmp(selection,'Quantify')
                        app.max_steps = 7; % Go directly to the quantify step
                    else
                        app.max_steps = -1;
                    end
                end
            end
            
            % Brain mask application - Need a brain mask from the MRI
            % process
            if app.max_steps < 0
                d.Message = 'Brain mask application';
                d.Value = 1/max_processings;
                msg = app.MRSI_experiment.Brain_mask_comp(app.MRIReg_element.Brain_mask);
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Spectroscopic imaging processing')
                    ok = 0;
                    return
                end
                figure(app.UIFigure)
                app.max_steps = 0;
            end
            
            % Linewidth map
            if app.max_steps < 1
                d.Message = 'Linewidth map';
                d.Value = 2/max_processings;
                msg = app.MRSI_experiment.Linewidth_map_comp();
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Spectroscopic imaging processing')
                    ok = 0;
                    return
                end
                figure(app.UIFigure)
                app.max_steps = 1;
            end
           

            % HSVD - Hankel Lanczos Singular value decomposition
            if app.max_steps < 2
                d.Message = 'HSVD';
                d.Value = 3/max_processings;
                msg = app.MRSI_experiment.custom_HSVD(d);
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Spectroscopic imaging processing')
                    ok = 0;
                    return
                end
                figure(app.UIFigure)
                app.max_steps = 2;
            end


            % SNR map
            if app.max_steps < 3
                d.Message = 'SNR map';
                d.Value = 4/max_processings;
                msg = app.MRSI_experiment.SNR_map_comp();
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Spectroscopic imaging processing')
                    ok = 0;
                    return
                end
                figure(app.UIFigure)
                app.max_steps = 3;
            end

            % Lipid suppression
            if and(app.MRSI_LipidsupButton.Value,app.max_steps < 4)
                d.Message = 'Lipid suppression';
                d.Value = 5/max_processings;
                msg = app.MRSI_experiment.LipSup_MRSI();
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Spectroscopic imaging processing')
                    ok = 0;
                    return
                end
                figure(app.UIFigure)
                app.max_steps = 4;
            end


            % Fillgaps
            if and(app.MRSI_FillgapsButton.Value,app.max_steps < 5)
                d.Message = 'Fillgaps';
                d.Value = 6/max_processings;
                msg = app.MRSI_experiment.Fillgaps_MRSI();
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Spectroscopic imaging processing')
                    ok = 0;
                    return
                end
                figure(app.UIFigure)
                app.max_steps = 5;
            end

            % Quality masks
            if app.max_steps < 6
                d.Message = 'Quality masks';
                d.Value = 7/max_processings;
                msg = app.MRSI_experiment.quality_masks();
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Spectroscopic imaging processing')
                    ok = 0;
                    return
                end
                figure(app.UIFigure)
                app.max_steps = 6;
            end

            % Save data
            if app.max_steps < 7
                d.Message = 'Saving data in RAW';
                d.Value = 8/max_processings;
                msg = app.MRSI_experiment.save_data();
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Spectroscopic imaging processing')
                    ok = 0;
                    return
                end
                figure(app.UIFigure)
                app.max_steps = 7;
            end

            % Quantification - Longest step (up to 1 hour !)
            if app.max_steps < 8
                d.Message = 'Quantification with LCModel';
                d.Value = 9/max_processings;
                msg = app.MRSI_experiment.quantify_data(app.mrsi_param);
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Spectroscopic imaging processing')
                    ok = 0;
                    return
                end
                figure(app.UIFigure)
                app.max_steps = 8;
            end

            % Create metabolite map
            if app.max_steps < 9
                d.Message = 'Creation of metabolite maps';
                d.Value = 10/max_processings;
                pause(1)
                msg = app.MRSI_experiment.read_coord_tables();
                if any(~cellfun(@isempty,msg))
                    uialert(app.UIFigure,msg,'Spectroscopic imaging processing')
                    ok = 0;
                    return
                end
                figure(app.UIFigure)
                app.max_steps = 9;
            end

            d.Message = 'Finishing the processing';
            d.Value = 11/max_processings;
            pause(2)
            close(d)
            
            % Current slice shown
            Slice_index = str2double(app.MRSI_Slice_DropDown.Value);
            app.current_mask = squeeze(app.MRSI_experiment.Final_mask(Slice_index,:,:));
            assignin("base","MRSI_experiment",app.MRSI_experiment) % Saving on Matlab workspace
        end

        %% SVS/DWS private functions

        function SVS_DWS_experiments(app)
            if strcmp(app.Spec_calc,'SVS')
                ref_data = app.Ref_data_SVS_DropDown.Value;
                expnames = {app.struct_svs_exp.exp_name};
                app.Ref_data_SVS_DropDown.Items = [{'None'},expnames];
                if isempty(ref_data)
                    app.Ref_data_SVS_DropDown.Value = expnames{1};
                    % app.struct_svs_exp(1).ref_bool = true;
                end
                if length(expnames) > 1
                    idx_met = find(~[app.struct_svs_exp.ref_bool]);
                    State = [app.struct_svs_exp.met_bool];
                    table_met = table(State(idx_met).',expnames(idx_met).', ...
                        'VariableNames',{'On/Off','Experiment name'});
                    app.UITable_met_SVS.Data = table_met;
                end
            elseif strcmp(app.Spec_calc,'DWS')
                ref_data = app.Ref_data_DWS_DropDown.Value;
                expnames = {app.struct_dws_exp.exp_name};
                app.Ref_data_DWS_DropDown.Items = [{'None'},expnames];
                if isempty(ref_data)
                    app.Ref_data_DWS_DropDown.Value = expnames{1};
                    % app.struct_dws_exp(1).ref_bool = true;
                end
                if length(expnames) > 1
                    idx_met = find(~[app.struct_dws_exp.ref_bool]);
                    State = [app.struct_dws_exp.met_bool];
                    table_met = table(State(idx_met).',expnames(idx_met).', ...
                        'VariableNames',{'On/Off','Experiment name'});
                    app.UITable_met_DWS.Data = table_met;
                end
            end
        end
        

        function disp_SVS_check(app)
            % IF quantifed, no reference spectra quantified
            if strcmp(app.SVS_TypeDropDown.Value,'quantified')
                exp_name = {app.SVS_experiment.SVS_struct.exp_name};
                idx_met = [app.SVS_experiment.SVS_struct.met_bool];
                app.SVS_ExperimentDropDown.Items = exp_name(idx_met);
                app.SVS_DataButton.Visible = 'on';
                app.SVS_FitButton.Visible = 'on';
                app.SVS_BaselineButton.Visible = 'on';
                app.SVS_DataButton.Value = 1;
                app.SVS_FitButton.Value = 1;
            else
                current_exp = app.SVS_ExperimentDropDown.Value;
                app.SVS_ExperimentDropDown.Items = {app.SVS_experiment.SVS_struct.exp_name};
                app.SVS_ExperimentDropDown.Value = current_exp;
                app.SVS_DataButton.Visible = 'off';
                app.SVS_FitButton.Visible = 'off';
                app.SVS_BaselineButton.Visible = 'off';
            end
            % Type of study & number of spectra in current experiment
            exp_name = {app.SVS_experiment.SVS_struct.exp_name};
            idx_disp = find(strcmp(exp_name,app.SVS_ExperimentDropDown.Value));
            app.SVS_max_spectra = 1;
            if strcmp(app.SVS_TypeDropDown.Value,'raw')
                app.SVS_type_study = 'raw';
                app.SVS_max_spectra = app.SVS_experiment.SVS_struct(idx_disp).raw_study.multiplicity;
            elseif strcmp(app.SVS_TypeDropDown.Value,'processed')
                app.SVS_type_study = 'processed';
                app.SVS_max_spectra = app.SVS_experiment.SVS_struct(idx_disp).processed_study.multiplicity;
            elseif strcmp(app.SVS_TypeDropDown.Value,'processed + sum')
                app.SVS_type_study = 'sum_processed';
            elseif strcmp(app.SVS_TypeDropDown.Value,'quantified')
                app.SVS_type_study = 'quantified';
            end
            if app.SVS_Spectrum_nb_EditField.Value > app.SVS_max_spectra
                app.SVS_Spectrum_nb_EditField.Value = 1;
            end
            % Navigation between spectra enabled
            if app.SVS_max_spectra > 1
                if app.SVS_Spectrum_nb_EditField.Value > 1
                    app.SVS_Previous_spectrum_Button.Enable = 'on';
                else
                    app.SVS_Previous_spectrum_Button.Enable = 'off';
                end
                if app.SVS_Spectrum_nb_EditField.Value < app.SVS_max_spectra
                    app.SVS_Next_spectrum_Button.Enable = 'on';
                else
                    app.SVS_Next_spectrum_Button.Enable = 'off';
                end
            else
                app.SVS_Previous_spectrum_Button.Enable = 'off';
                app.SVS_Next_spectrum_Button.Enable = 'off';
            end
        end

        
        function display_SVS_spectra(app)
            if ~isempty(app.SVS_experiment)
                exp_name = {app.SVS_experiment.SVS_struct.exp_name};
                idx_disp = find(strcmp(exp_name,app.SVS_ExperimentDropDown.Value));
                multi = app.SVS_Spectrum_nb_EditField.Value;
                if ~strcmp(app.SVS_type_study,'quantified')
                    [scale_ppm,data_spec] = app.SVS_experiment.plot_signal(idx_disp,multi,app.SVS_type_study);
                    if app.SVS_absButton.Value
                        plot(app.UIAxes_SVS,scale_ppm,abs(data_spec));
                    elseif app.SVS_realButton.Value
                        plot(app.UIAxes_SVS,scale_ppm,real(data_spec));
                    elseif app.SVS_imagButton.Value
                        plot(app.UIAxes_SVS,scale_ppm,imag(data_spec));
                    end
                    xlim(app.UIAxes_SVS,[min(scale_ppm) max(scale_ppm)])
                    ylim(app.UIAxes_SVS,"auto")
                    set(app.UIAxes_SVS,'Xdir','reverse')
                else
                    quantif_fit = app.SVS_experiment.SVS_struct(idx_disp).quantif_fit;
                    ppm_scale = quantif_fit.ppm_pts;
                    cla(app.UIAxes_SVS)
                    hold(app.UIAxes_SVS,"on")
                    if app.SVS_DataButton.Value
                        plot(app.UIAxes_SVS,ppm_scale,quantif_fit.data_pts,'k-');
                    end
                    if app.SVS_FitButton.Value
                        plot(app.UIAxes_SVS,ppm_scale,quantif_fit.fit_pts,'r-','LineWidth',1.5);
                    end
                    if app.SVS_BaselineButton.Value
                        plot(app.UIAxes_SVS,ppm_scale,quantif_fit.baseline_pts,'-','LineWidth', ...
                            1.5,'Color',[0 0.4470 0.7410]);
                    end
                    xlim(app.UIAxes_SVS,[min(ppm_scale) max(ppm_scale)])
                    ylim(app.UIAxes_SVS,"auto")
                    hold(app.UIAxes_SVS,"off")
                end
            end
        end

        
        function display_SVS_results(app)
            if app.SVS_quantif_bool
                exp_name = {app.SVS_experiment.SVS_struct.exp_name};
                idx_disp = find(strcmp(exp_name,app.SVS_ExperimentDropDown.Value));
                if ~app.SVS_experiment.SVS_struct(idx_disp).ref_bool
                    quantif_res = app.SVS_experiment.SVS_struct(idx_disp).quantif_res;
                    table_results = table(app.SVS_experiment.Met_names,quantif_res.rel_conc,...
                        quantif_res.abs_conc,round(quantif_res.CRLB*100),...
                        'VariableNames',{'Metabolite names','Relative conc','Absolute conc','CRLB [%]'});
                    app.UITable_SVS_results.Data = table_results;
                else
                    app.UITable_SVS_results.Data = [];
                end
            end
        end


        function SVS_check_study_folder(app)
            if isfolder(fullfile(app.result_dir,app.study_name))
                svs_dir = dir(fullfile(app.result_dir,app.study_name,'*_SVS_experiment.mat'));
                if ~isempty(svs_dir)
                    svs_exp_temp = load(fullfile(app.result_dir,app.study_name,svs_dir.name));
                    if isfield(svs_exp_temp,'SVS_experiment')
                        svs_exp = svs_exp_temp.SVS_experiment;
                    else
                        return
                    end
                    if isa(svs_exp,'SVSClass')
                        app.SVS_experiment = svs_exp;
                        SVS_struct = app.SVS_experiment.SVS_struct;
                        app.struct_svs_exp = rmfield(SVS_struct, ...
                            setdiff(fieldnames(SVS_struct),{'folder','expnb','exp_name','ref_bool','met_bool'}));
                        app.SVS_DWS_experiments();
                        app.SVS_Data_Processing_Button.Enable = 'on';
                        app.SVS_Quantification_Button.Enable = 'on';
                        app.SVS_TypeDropDown.Items = {'raw','processed','processed + sum','quantified'};
                        app.SVS_TypeDropDown.Value = 'quantified';
                        exp_name = {app.SVS_experiment.SVS_struct.exp_name};
                        idx_met = [app.DWS_experiment.SVS_struct.met_bool];
                        app.SVS_ExperimentDropDown.Items = exp_name(idx_met);
                        app.disp_SVS_check()
                        app.SVS_quantif_bool = 1;
                        % Display the spectra
                        app.display_SVS_spectra()
                        % Display results in table
                        app.display_SVS_results()
                        app.SVS_Metabolite_fit_DropDown.Items = app.SVS_experiment.Met_names;
                    end
                end
            end
        end


        function disp_DWS_check(app)
            % IF quantifed, no reference spectra quantified
            if strcmp(app.DWS_TypeDropDown.Value,'quantified')
                exp_name = {app.DWS_experiment.DWS_struct.exp_name};
                idx_met = [app.DWS_experiment.DWS_struct.met_bool];
                app.DWS_ExperimentDropDown.Items = exp_name(idx_met);
                app.DWS_DataButton.Visible = 'on';
                app.DWS_FitButton.Visible = 'on';
                app.DWS_BaselineButton.Visible = 'on';
                app.DWS_DataButton.Value = 1;
                app.DWS_FitButton.Value = 1;
            else
                current_exp = app.DWS_ExperimentDropDown.Value;
                app.DWS_ExperimentDropDown.Items = {app.DWS_experiment.DWS_struct.exp_name};
                app.DWS_ExperimentDropDown.Value = current_exp;
                app.DWS_DataButton.Visible = 'off';
                app.DWS_FitButton.Visible = 'off';
                app.DWS_BaselineButton.Visible = 'off';
            end
            % Type of study & number of spectra in current experiment
            exp_name = {app.DWS_experiment.DWS_struct.exp_name};
            idx_disp = find(strcmp(exp_name,app.DWS_ExperimentDropDown.Value));
            app.DWS_max_spectra = 1;
            if strcmp(app.DWS_TypeDropDown.Value,'raw')
                app.DWS_type_study = 'raw';
                app.DWS_max_spectra = app.DWS_experiment.DWS_struct(idx_disp).raw_study.multiplicity;
            elseif strcmp(app.DWS_TypeDropDown.Value,'processed')
                app.DWS_type_study = 'processed';
                app.DWS_max_spectra = app.DWS_experiment.DWS_struct(idx_disp).processed_study.multiplicity;
            elseif strcmp(app.DWS_TypeDropDown.Value,'processed + sum')
                app.DWS_type_study = 'sum_processed';
            elseif strcmp(app.DWS_TypeDropDown.Value,'quantified')
                app.DWS_type_study = 'quantified';
            end
            if app.DWS_Spectrum_nb_EditField.Value > app.DWS_max_spectra
                app.DWS_Spectrum_nb_EditField.Value = 1;
            end
            % Navigation between spectra enabled
            if app.DWS_max_spectra > 1
                if app.DWS_Spectrum_nb_EditField.Value > 1
                    app.DWS_Previous_spectrum_Button.Enable = 'on';
                else
                    app.DWS_Previous_spectrum_Button.Enable = 'off';
                end
                if app.DWS_Spectrum_nb_EditField.Value < app.DWS_max_spectra
                    app.DWS_Next_spectrum_Button.Enable = 'on';
                else
                    app.DWS_Next_spectrum_Button.Enable = 'off';
                end
            else
                app.DWS_Previous_spectrum_Button.Enable = 'off';
                app.DWS_Next_spectrum_Button.Enable = 'off';
            end
        end

        
        function display_DWS_spectra(app)
            if ~isempty(app.DWS_experiment)
                exp_name = {app.DWS_experiment.DWS_struct.exp_name};
                idx_disp = find(strcmp(exp_name,app.DWS_ExperimentDropDown.Value));
                multi = app.DWS_Spectrum_nb_EditField.Value;
                if ~strcmp(app.DWS_type_study,'quantified')
                    [scale_ppm,data_spec] = app.DWS_experiment.plot_signal(idx_disp,multi,app.DWS_type_study);
                    if app.DWS_absButton.Value
                        plot(app.UIAxes_DWS,scale_ppm,abs(data_spec));
                    elseif app.DWS_realButton.Value
                        plot(app.UIAxes_DWS,scale_ppm,real(data_spec));
                    elseif app.DWS_imagButton.Value
                        plot(app.UIAxes_DWS,scale_ppm,imag(data_spec));
                    end
                    xlim(app.UIAxes_DWS,[min(scale_ppm) max(scale_ppm)])
                    ylim(app.UIAxes_DWS,"auto")
                    set(app.UIAxes_DWS,'Xdir','reverse')
                else
                    quantif_fit = app.DWS_experiment.DWS_struct(idx_disp).quantif_fit;
                    ppm_scale = quantif_fit.ppm_pts;
                    cla(app.UIAxes_DWS)
                    hold(app.UIAxes_DWS,"on")
                    if app.DWS_DataButton.Value
                        plot(app.UIAxes_DWS,ppm_scale,quantif_fit.data_pts,'k-');
                    end
                    if app.DWS_FitButton.Value
                        plot(app.UIAxes_DWS,ppm_scale,quantif_fit.fit_pts,'r-','LineWidth',1.5);
                    end
                    if app.DWS_BaselineButton.Value
                        plot(app.UIAxes_DWS,ppm_scale,quantif_fit.baseline_pts,'-','LineWidth', ...
                            1.5,'Color',[0 0.4470 0.7410]);
                    end
                    xlim(app.UIAxes_DWS,[min(ppm_scale) max(ppm_scale)])
                    ylim(app.UIAxes_DWS,"auto")
                    hold(app.UIAxes_DWS,"off")
                end
            end
        end

        
        function display_DWS_results(app)
            if app.DWS_quantif_bool
                exp_name = {app.DWS_experiment.DWS_struct.exp_name};
                idx_disp = find(strcmp(exp_name,app.DWS_ExperimentDropDown.Value));
                if ~app.DWS_experiment.DWS_struct(idx_disp).ref_bool
                    quantif_res = app.DWS_experiment.DWS_struct(idx_disp).quantif_res;
                    table_results = table(app.DWS_experiment.Met_names,quantif_res.rel_conc,...
                        quantif_res.abs_conc,round(quantif_res.CRLB*100),...
                        'VariableNames',{'Metabolite names','Relative conc','Absolute conc','CRLB [%]'});
                    app.UITable_DWS_results.Data = table_results;
                else
                    app.UITable_DWS_results.Data = [];
                end
            end
        end
        

        function plot_DWS_fitting(app)
            % Oriented sticks model
            pa_sticks = @(Da,b) sqrt(pi./(4*b.*Da)).*erf(sqrt(b.*Da));
            % Kurtosis model
            kurtosis = @(b,D,K) exp(-b.*D + 1/6.*b.^2*D.^2.*K);

            rc = app.DWS_experiment.radius_conv; %21; % radius of convergence of kurtosis model
            excluded_bval = app.DWS_experiment.excluded_bval; % excluded points above this b-value
            
            rel_conc_tot = app.DWS_experiment.abs_conc_tot;
            std_rel_conc_tot = rel_conc_tot .* app.DWS_experiment.crlb_tot;
            Da_fit = app.DWS_experiment.Da_fit;
            adc = app.DWS_experiment.adc;
            akc = app.DWS_experiment.akc;

            idx_met = find(strcmp(app.DWS_experiment.Met_names,app.DWS_Metabolite_fit_DropDown.Value));

            if ~isnan(Da_fit(idx_met))
                app.Da_fit_EditField.Value = Da_fit(idx_met);
                app.ADC_EditField.Value = adc(idx_met);
                app.AKC_EditField.Value = akc(idx_met);
                b = app.DWS_experiment.b_value;
                [b,idx_b] = sort(b);
                index = b < excluded_bval;
                Y = squeeze(rel_conc_tot(idx_met,:)) / squeeze(rel_conc_tot(idx_met,1));
                Ysd = squeeze(std_rel_conc_tot(idx_met,:)) / squeeze(rel_conc_tot(idx_met,1));
                Ysd = Y.* sqrt(Ysd.^2 + Ysd(1).^2);
                Y = Y(idx_b); Ysd = Ysd(idx_b);
                b = b(~isnan(Y)); Y_ = Y(~isnan(Y))'; Ysd = Ysd(~isnan(Ysd))';
                cla(app.UIAxes_DWS_fitting)
                hold(app.UIAxes_DWS_fitting,'on')
                errorbar(app.UIAxes_DWS_fitting,b(index),Y_(index), ...
                    Ysd(index),'k.','MarkerSize',8,'LineWidth',1);
                plot(app.UIAxes_DWS_fitting,b(index),pa_sticks(Da_fit(idx_met),b(index)),'--', ...
                    'Color',[231,153,39]./256,'LineWidth',1.5);
                index = b < rc;
                plot(app.UIAxes_DWS_fitting,b(index),kurtosis(b(index),adc(idx_met),akc(idx_met)),'--', ...
                    'Color',[117,197,155]./256,'LineWidth',1.5);
                hold(app.UIAxes_DWS_fitting,'off')
            end
        end
        

        function DWS_check_study_folder(app)
            if isfolder(fullfile(app.result_dir,app.study_name))
                dws_dir = dir(fullfile(app.result_dir,app.study_name,'*_DWS_experiment.mat'));
                if ~isempty(dws_dir)
                    dws_exp_temp = load(fullfile(app.result_dir,app.study_name,dws_dir.name));
                    if isfield(dws_exp_temp,'DWS_experiment')
                        dws_exp = dws_exp_temp.DWS_experiment;
                    else
                        return
                    end
                    if isa(dws_exp,'DWSClass')
                        app.DWS_experiment = dws_exp;
                        DWS_struct = app.DWS_experiment.DWS_struct;
                        app.struct_dws_exp = rmfield(DWS_struct, ...
                            setdiff(fieldnames(DWS_struct),{'folder','expnb','exp_name','ref_bool','met_bool'}));
                        app.SVS_DWS_experiments();
                        app.DWS_Data_Processing_Button.Enable = 'on';
                        app.DWS_Quantification_Button.Enable = 'on';
                        app.DWS_TypeDropDown.Items = {'raw','processed','processed + sum','quantified'};
                        app.DWS_TypeDropDown.Value = 'quantified';
                        exp_name = {app.DWS_experiment.DWS_struct.exp_name};
                        idx_met = [app.DWS_experiment.DWS_struct.met_bool];
                        app.DWS_ExperimentDropDown.Items = exp_name(idx_met);
                        app.disp_DWS_check()
                        app.DWS_quantif_bool = 1;
                        % Display the spectra
                        app.display_DWS_spectra()
                        % Display results in table
                        app.display_DWS_results()
                        app.DWS_Metabolite_fit_DropDown.Items = app.DWS_experiment.Met_names;
                        % Display diffusion fitting
                        app.plot_DWS_fitting()
                    end
                end
            end
        end
        
        
    end
    
    methods (Access = public)
        
        % Function to display the MRSI data (image slice & metabolite map)
        function display_MRSI(app)
            % Axes settings clearing
            cla(app.UIAxes_MRSI_1)
            cla(app.UIAxes_MRSI_2)

            % MRSI Matrix parameter
            Matrix_Size = app.MRSI_experiment.acq_params.matrix_sz;

            % Check if SNR & LW exist
            N_tot_Slices = app.MRSI_Number_slices_Spinner.Value;
            if(isempty(app.MRSI_experiment.SNR_map))
                app.MRSI_experiment.SNR_map = zeros(N_tot_Slices,Matrix_Size(1),Matrix_Size(2));
                for kk=1:N_tot_Slices
                    temp_path = fullfile(app.MRSI_experiment.data_folder,num2str(app.MRSI_experiment.metab_expnb),['SNR_map_' num2str(kk) '.mat']);
                    if(exist(temp_path))
                        temp_SNR = load(temp_path);
                        app.MRSI_experiment.SNR_map(kk,:,:) = temp_SNR.SNR_data;
                    else
                        app.MRSI_MetaboliteDropDown.Value = 'NAA';
                    end
                end
            end

            if(isempty(app.MRSI_experiment.Linewidth_map))
                app.MRSI_experiment.Linewidth_map = zeros(N_tot_Slices,Matrix_Size(1),Matrix_Size(2));
                for kk=1:N_tot_Slices
                    temp_path = fullfile(app.MRSI_experiment.data_folder,num2str(app.MRSI_experiment.metab_expnb),['Lw_map_' num2str(kk) '.mat']);
                    if(exist(temp_path))
                        temp_Lw = load(temp_path);
                        app.MRSI_experiment.Linewidth_map(kk,:,:) = temp_Lw.FWHM_map;
                    else
                        app.MRSI_MetaboliteDropDown.Value = 'NAA';
                    end
                end
            end

            Slice_index = str2double(app.MRSI_Slice_DropDown.Value);
            temp_mask = logical(squeeze(app.MRSI_experiment.Final_mask(Slice_index,:,:)));
            temp_SNRmap = squeeze(app.MRSI_experiment.SNR_map(Slice_index,:,:));
            temp_Lwmap = squeeze(app.MRSI_experiment.Linewidth_map(Slice_index,:,:));
            app.MRSI_experiment.avg_SNR = mean(temp_SNRmap(temp_SNRmap>0));
            app.avg_SNR = app.MRSI_experiment.avg_SNR;
            app.MRSI_experiment.avg_Linewidth = mean(temp_Lwmap(temp_Lwmap>0));
            app.avg_Lw = app.MRSI_experiment.avg_Linewidth;


            Met_index = find(strcmp(app.MRSI_MetaboliteDropDown.Items, app.MRSI_MetaboliteDropDown.Value));
            if length(Met_index) > 1 % Prevent 2 or more metabolites with the same name
                Met_index = Met_index(1);
            end
            mask_plot = 0; % Plot the current mask instead of metabolites
            power_map_plot = 0; % Plot the power map
            SNR_plot = 0; % Plot the SNR map
            lw_plot = 0; % Plot the Lw map

            if Met_index == 1
                mask_plot = 1;
            elseif Met_index == 2
                power_map_plot = 1;
            elseif Met_index == 3
                SNR_plot = 1;
            elseif Met_index == 4
                lw_plot = 1;
            else
                Met_index = Met_index - 4;
            end
            Slice_index = str2double(app.MRSI_Slice_DropDown.Value);

            % MRI
            min_slice = app.Slices_number(Slice_index) - app.slice_range; % minimum slice in the range
            max_slice = app.Slices_number(Slice_index) + app.slice_range; % maximum slice in the range
            Image_slice = rot90(squeeze(sum(double(app.MRIReg_element.original_image(:,:,min_slice:max_slice)),3)),-1);
            Image_slice = uint8(Image_slice/max(max(Image_slice))*256);
            Image_Size = size(Image_slice);

            % MRSI
            if ~mask_plot && ~power_map_plot && ~SNR_plot && ~lw_plot
                if strcmp(app.MRSI_ConcentrationsDropDown.Value,'Relative')
                    if app.MRSI_Job0CheckBox.Value
                        map = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).rel_conc(Met_index,:,:)); 
                    else
                        map = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).rel_conc(Met_index,end:-1:1,:)); 
                    % Mirror on the lines (BECAUSE OF THE H-F ENCODING ORIENTATION)
                    end

                    if app.mri_headprone
                        map = map(end:-1:1,:);
                    end
                elseif strcmp(app.MRSI_ConcentrationsDropDown.Value,'CRLB')
                    if app.MRSI_Job0CheckBox.Value
                        map = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).CRB(Met_index,:,:))*100; 
                    else
                        map = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).CRB(Met_index,end:-1:1,:))*100; 
                    % Mirror on the lines (BECAUSE OF THE H-F ENCODING ORIENTATION)
                    end

                    if app.mri_headprone
                        map = map(end:-1:1,:);
                    end
                else
                    if app.MRSI_Job0CheckBox.Value
                        map = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).abs_conc(Met_index,:,:));  
                    else
                        map = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).abs_conc(Met_index,end:-1:1,:)); 
                    % Mirror on the lines (BECAUSE OF THE H-F ENCODING ORIENTATION)
                    end

                    if app.mri_headprone
                        map = map(end:-1:1,:);
                    end
                end

                if app.MRSI_Job0CheckBox.Value
                    CRLBs = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).CRB(Met_index,:,:)); 
                else
                    CRLBs = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).CRB(Met_index,end:-1:1,:));
                end

                if app.mri_headprone
                        CRLBs = CRLBs(end:-1:1,:);
                end
                CRLBs_mask = and(CRLBs < app.disp_opt.CRLB_limit/100, CRLBs ~= 0);

                if app.disp_opt.LCModel_qc
                    if app.MRSI_Job0CheckBox.Value
                        SNR_map = app.MRSI_experiment.Final_met_map(Slice_index).SNR(:,:);
                        FWHM_map = app.MRSI_experiment.Final_met_map(Slice_index).FWHM(:,:);
                    else
                        SNR_map = app.MRSI_experiment.Final_met_map(Slice_index).SNR(end:-1:1,:);
                        FWHM_map = app.MRSI_experiment.Final_met_map(Slice_index).FWHM(end:-1:1,:);
                    end

                    if app.mri_headprone
                        SNR_map = SNR_map(end:-1:1,:);
                        FWHM_map = FWHM_map(end:-1:1,:);
                    end
                else
                    if app.MRSI_Job0CheckBox.Value
                        SNR_map = squeeze(app.MRSI_experiment.SNR_map(Slice_index,:,:));
                        FWHM_map = app.MRSI_experiment.Final_met_map(Slice_index).FWHM(:,:);
                        % FWHM_map = squeeze(app.MRSI_experiment.Linewidth_map(Slice_index,:,:));
                    else
                        SNR_map = squeeze(app.MRSI_experiment.SNR_map(Slice_index,end:-1:1,:));
                        FWHM_map = app.MRSI_experiment.Final_met_map(Slice_index).FWHM(end:-1:1,:);
                        % FWHM_map = squeeze(app.MRSI_experiment.Linewidth_map(Slice_index,end:-1:1,:));
                    end

                    if app.mri_headprone
                        SNR_map = SNR_map(end:-1:1,:);
                        FWHM_map = FWHM_map(end:-1:1,:);
                    end
                end

                mean_SNR = mean(SNR_map(SNR_map > 0));
                app.MRSI_mean_SNR = mean_SNR;
                % if exist(app.DisplayOptionsApp.)
                mean_FWHM = mean(FWHM_map(FWHM_map > 0));
                app.MRSI_mean_FWHM = mean_FWHM;
                if app.disp_opt.SNR_qc
                    if app.disp_opt.SNR_th_bool
                        SNR_mask = SNR_map > app.disp_opt.SNR_min_th*mean_SNR;
                    else
                        SNR_mask = SNR_map >= app.disp_opt.SNR_min_val;
                    end
                else
                    SNR_mask = true(size(SNR_map));
                end
                if app.disp_opt.FWHM_qc
                    if app.disp_opt.FWHM_th_bool
                        FWHM_mask = FWHM_map < app.disp_opt.FWHM_max_th*mean_FWHM;
                    else
                        FWHM_mask = FWHM_map < app.disp_opt.FWHM_max_val;
                    end
                else
                    FWHM_mask = true(size(FWHM_map));
                end
                map_total = map .* CRLBs_mask .* SNR_mask .* FWHM_mask;
                map_mask = logical(app.current_mask) .* CRLBs_mask .* SNR_mask .* FWHM_mask;
    
                max_map = max(max(map_total));
                
                app.current_metab_map = map_total .* logical(app.current_mask);
    
                % Interpolation of the map
                if app.disp_opt.interpol_bool
                    [X,Y] = meshgrid(1:Matrix_Size(1),1:Matrix_Size(2));
                    [XI,YI] = meshgrid(1:(Matrix_Size(1)-1)/(Image_Size(1)-1):Matrix_Size(1), ...
                        1:(Matrix_Size(2)-1)/(Image_Size(2)-1):Matrix_Size(2));
                    lin = interp2(X,Y,map_total,XI,YI,'cubic');
                    BA = lin;
                    lin2 = interp2(X,Y,double(map_mask),XI,YI,'cubic');
                    CC = lin2;
                    CC = CC > 0.7;
                    BA = BA .* CC;
                else
                    BA = app.current_metab_map;
                end
                if strcmp(app.MRSI_ConcentrationsDropDown.Value,'Relative')
                    app.clrbar.Label.String = sprintf('Concentration /%s',app.mrsi_param.Rel_met);
                elseif strcmp(app.MRSI_ConcentrationsDropDown.Value,'CRLB')
                    app.clrbar.Label.String = 'CRLB [%]';
                else
                    app.clrbar.Label.String = 'Concentration [mmol/l]';
                end
            elseif ~power_map_plot && ~SNR_plot && ~lw_plot
                BA = double(squeeze(app.MRSI_experiment.Final_mask(Slice_index,:,:)));
            elseif ~SNR_plot && ~lw_plot
                if app.MRSI_Job0CheckBox.Value
                    BA = double(squeeze(app.MRSI_experiment.Power_map(Slice_index,:,:)));
                else
                    BA = double(squeeze(app.MRSI_experiment.Power_map(Slice_index,end:-1:1,:)));
                end

                if app.mri_headprone
                    BA = BA(end:-1:1,:);
                end
                app.clrbar.Label.String = sprintf('Water Signal Power [W]');
            elseif ~lw_plot
                if app.MRSI_Job0CheckBox.Value
                    BA = double(squeeze(app.MRSI_experiment.SNR_map(Slice_index,:,:)));
                else
                    BA = double(squeeze(app.MRSI_experiment.SNR_map(Slice_index,end:-1:1,:)).*squeeze(app.MRSI_experiment.Final_mask(Slice_index,:,:)));
                end

                if app.mri_headprone
                    BA = BA(end:-1:1,:);
                end
                app.clrbar.Label.String = sprintf('SNR [a.u.]');
            else
                if app.MRSI_Job0CheckBox.Value
                    BA = double(squeeze(app.MRSI_experiment.Linewidth_map(Slice_index,:,:)));
                else
                    BA = double(squeeze(app.MRSI_experiment.Linewidth_map(Slice_index,end:-1:1,:)).*squeeze(app.MRSI_experiment.Final_mask(Slice_index,:,:)));
                end

                if app.mri_headprone
                    BA = BA(end:-1:1,:);
                end
                app.clrbar.Label.String = sprintf('Linewidth [Hz]');
            end

            % Plot current slice
            im_MRI = imshow(Image_slice,'Parent',app.UIAxes_MRSI_1);
            im_MRI.AlphaData = 1; % change this value to change the background image transparency
            axis(app.UIAxes_MRSI_1,'equal')

            % Plot concentration map
            im_MRSI = imagesc(app.UIAxes_MRSI_2,BA);
            im_MRSI.AlphaData = 0.4; % change this value to change the foreground image transparency
            axis(app.UIAxes_MRSI_2,'equal')

            % Add different colormap to different data if you wish
            colormap(app.UIAxes_MRSI_1,'gray')
            if mask_plot
                mapcolors = [0.15 0.15 0.15; 0.07 0.62 1];
                cmap = [mapcolors(1,:); ...
                    mapcolors(2,:)];
                colormap(app.UIAxes_MRSI_2,cmap)
            else
                colormap(app.UIAxes_MRSI_2,'jet')
            end

            if ~mask_plot && ~power_map_plot && ~SNR_plot && ~lw_plot
                app.clrbar.Visible = 'on';
                app.clrbar.Position = [0.9, 0.24, 0.025, 0.71];
                if strcmp(app.MRSI_ConcentrationsDropDown.Value,'Relative')
                    if app.disp_opt.auto_max_conc
                        if and(max_map > 0, max_map < 20)
                            app.max_clrb = max_map;
                        else
                            app.max_clrb = 15;
                        end
                    else
                        app.max_clrb = app.disp_opt.max_conc;
                    end
                elseif strcmp(app.MRSI_ConcentrationsDropDown.Value,'CRLB')
                    app.max_clrb = app.disp_opt.CRLB_limit;
                else
                    if and(max_map > 0,max_map < 10000)
                        app.max_clrb = max_map;
                    else
                        app.max_clrb = 10000;
                    end
                end
            elseif ~power_map_plot && ~SNR_plot && ~lw_plot
                
                app.clrbar.Visible = 'off';
                app.clrbar.Position = [1.1, 1.1, 0.025, 0.71];
            elseif ~SNR_plot && ~lw_plot
                max_map = max(max(BA));
                app.max_clrb = max_map;
                app.clrbar.Visible = 'on';
                app.clrbar.Position = [0.9, 0.24, 0.025, 0.71];
            elseif ~lw_plot
                max_map = 100;
                app.max_clrb = max_map;
                app.clrbar.Visible = 'on';
                app.clrbar.Position = [0.9, 0.24, 0.025, 0.71];
            else 
                max_map = 60;
                app.max_clrb = max_map;
                app.clrbar.Visible = 'on';
                app.clrbar.Position = [0.9, 0.24, 0.025, 0.71];
            end
            clim(app.UIAxes_MRSI_2,[0,app.max_clrb])
        end
        
        % Function to create a structure with the relative and absolute
        % concentration of each metabolite on the current region mask
        % as well as the statistics (mean and std) related to it
        function results = MRSI_calculate_conc(app)
            Slice_index = str2double(app.MRSI_Slice_DropDown.Value);
            
            if app.MRSI_Job0CheckBox.Value
                % Relative concentration
                maps_rel = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).rel_conc(:,:,:));
                % Absolute concentration
                maps_abs = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).abs_conc(:,:,:));
            else
                % Relative concentration
                maps_rel = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).rel_conc(:,end:-1:1,:));
                % Absolute concentration
                maps_abs = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).abs_conc(:,end:-1:1,:));
            end

            if app.mri_headprone
                maps_rel = maps_rel(:,end:-1:1,:);
                maps_abs = maps_abs(:,end:-1:1,:);
            end
            
            met_names = app.MRSI_experiment.Met_names;
            % Quality controls
            if app.MRSI_Job0CheckBox.Value
                CRLBs = app.MRSI_experiment.Final_met_map(Slice_index).CRB(:,:,:);
            else
                CRLBs = app.MRSI_experiment.Final_met_map(Slice_index).CRB(:,end:-1:1,:);
            end

            if app.mri_headprone
                CRLBs = CRLBs(:,end:-1:1,:);
            end

            CRLBs_mask = and(CRLBs < app.disp_opt.CRLB_limit/100, CRLBs ~= 0);

            if app.disp_opt.LCModel_qc
                if app.MRSI_Job0CheckBox.Value
                    SNR_map = app.MRSI_experiment.Final_met_map(Slice_index).SNR(:,:);
                    FWHM_map = app.MRSI_experiment.Final_met_map(Slice_index).FWHM(:,:);
                else
                    SNR_map = app.MRSI_experiment.Final_met_map(Slice_index).SNR(end:-1:1,:);
                    FWHM_map = app.MRSI_experiment.Final_met_map(Slice_index).FWHM(end:-1:1,:);
                end

                if app.mri_headprone
                    SNR_map = SNR_map(end:-1:1,:);
                    FWHM_map = FWHM_map(end:-1:1,:);
                end
            else
                if app.MRSI_Job0CheckBox.Value
                    SNR_map = squeeze(app.MRSI_experiment.SNR_map(Slice_index,:,:));
                    FWHM_map = squeeze(app.MRSI_experiment.Linewidth_map(Slice_index,:,:));
                else
                    SNR_map = squeeze(app.MRSI_experiment.SNR_map(Slice_index,end:-1:1,:));
                    FWHM_map = squeeze(app.MRSI_experiment.Linewidth_map(Slice_index,end:-1:1,:));
                end

                if app.mri_headprone
                    SNR_map = SNR_map(end:-1:1,:);
                    FWHM_map = FWHM_map(end:-1:1,:);
                end
            end


            MRSI_Mean_SNR = mean(SNR_map(SNR_map > 0));
            MRSI_Mean_FWHM = mean(FWHM_map(FWHM_map > 0));
            if app.disp_opt.SNR_qc % SNR quality control
                    if app.disp_opt.SNR_th_bool
                        SNR_mask = SNR_map > app.disp_opt.SNR_min_th*MRSI_Mean_SNR;
                    else
                        SNR_mask = SNR_map >= app.disp_opt.SNR_min_val;
                    end
            else
                    SNR_mask = true(size(SNR_map));
            end
                if app.disp_opt.FWHM_qc % FWHM quality control
                    if app.disp_opt.FWHM_th_bool
                        FWHM_mask = FWHM_map < app.disp_opt.FWHM_max_th*MRSI_Mean_FWHM;
                    else
                        FWHM_mask = FWHM_map < app.disp_opt.FWHM_max_val;
                    end
                else
                    FWHM_mask = true(size(FWHM_map));
                end
            Results = struct; % Structure of results
            for met = 1:size(maps_rel,1) % Go through all the metabolites
                concs_rel = squeeze(maps_rel(met,:,:));
                concs_abs = squeeze(maps_abs(met,:,:));
                result_mask = squeeze(CRLBs_mask(met,:,:)) .* ...
                    logical(app.current_mask) .* SNR_mask .* FWHM_mask .* concs_rel > 0;
                % Relative concentration
                concs_rel = concs_rel(result_mask);
                if isempty(concs_rel)
                    mean_conc_rel = 0;
                    std_conc_rel = 0;
                else
                    mean_conc_rel = mean(concs_rel);
                    std_conc_rel = std(concs_rel);
                end
                % Absolute concentration
                concs_abs = concs_abs(result_mask);
                if isempty(concs_abs)
                    mean_conc_abs = 0;
                    std_conc_abs = 0;
                else
                    mean_conc_abs = mean(concs_abs);
                    std_conc_abs = std(concs_abs);
                end
                N_voxels = sum(sum(double(result_mask)));   % Number of MRSI voxels studied
                Results(met).met_names = met_names(met);    % Name of the metabolites
                Results(met).concs_rel = concs_rel;         % Relative concentration of each voxel
                Results(met).mean_conc_rel = mean_conc_rel; % Mean relative concentration on voxels
                Results(met).std_conc_rel = std_conc_rel;   % Standard deviation of the relative concentration
                Results(met).concs_abs = concs_abs;         % Absolute concentration of each voxel
                Results(met).mean_conc_abs = mean_conc_abs; % Mean absolute concentration on voxels
                Results(met).std_conc_abs = std_conc_abs;   % Standard debiation of the absolute concentration
                Results(met).n_voxels = N_voxels;           % Number of MRSI voxels studied
                Results(met).masks = squeeze(result_mask);  % Masks used from our quality controls
            end
            met_conc = Results;
            results = struct;
            results.region = app.region_name;               % Name of the studied region
            results.side = app.MRSI_SideDropDown.Value;     % Side of the brain studied 
            results.met_conc = met_conc;
        end

        % Function to display the brain region and side selectioned 
        function MRSI_display_region(app)
            app.original_region = 1; % Original segmentation (MRI process)
            idx_region = find(strcmp(app.MRSI_RegionDropDown.Value,app.MRSI_RegionDropDown.Items)); % region studied
            idx_side = find(strcmp(app.MRSI_SideDropDown.Value,app.MRSI_SideDropDown.Items)); % side studied 1:Left, 2:Right, 3+:L+R
            
            MatSize = app.MRSI_experiment.acq_params.matrix_sz; % MRSI voxels
            [Nx,Ny,~] = size(app.MRIReg_element.Brain_mask);
            current_slice = app.Slices_number(str2double(app.MRSI_Slice_DropDown.Value)); % current MRI slice
            range_slices = current_slice-app.slice_range:current_slice+app.slice_range; % range of slices studied
            
            if idx_region > length(app.MRIReg_element.Segmentation) % Whole Brain case
                Slice_index = str2double(app.MRSI_Slice_DropDown.Value);
                app.current_mask = squeeze(app.MRSI_experiment.Final_mask(Slice_index,:,:));
                app.MRSI_SideDropDown.Value = 'L+R'; % Total mask, no Left or Right
            else
                if idx_side == 1 % Left
                    label_slice_l = app.MRIReg_element.Segmentation(idx_region).Left;
                    label_slice = squeeze(sum(label_slice_l(:,:,range_slices),3));
                elseif idx_side == 2 % Right
                    label_slice_r = app.MRIReg_element.Segmentation(idx_region).Right;
                    label_slice = squeeze(sum(label_slice_r(:,:,range_slices),3));
                else % L+R
                    label_slice_l = app.MRIReg_element.Segmentation(idx_region).Left;
                    label_slice_l = squeeze(sum(label_slice_l(:,:,range_slices),3));
                    label_slice_r = app.MRIReg_element.Segmentation(idx_region).Right;
                    label_slice_r = squeeze(sum(label_slice_r(:,:,range_slices),3));
                    label_slice = label_slice_l + label_slice_r;
                end
                % Transformation from Nx x Ny to MatSize(1) x MatSize(2)
                % mask
                label_slice = double(logical(label_slice));
                super_image = kron(label_slice,ones(MatSize(1),MatSize(2)));
                R = reshape(super_image, Nx, MatSize(1), Ny, MatSize(2));
                S = sum(sum(R,1),3)/(Nx * Ny);
                final_slice = reshape(S, MatSize(1), MatSize(2));
                app.current_mask = (final_slice > app.overlap);
            end

            app.display_MRSI() % call the display MRSI function
        end
        
        function MRSI_display_custom_regions(app)
            app.original_region = 1; % Original segmentation (MRI process)
            
            MatSize = app.MRSI_experiment.acq_params.matrix_sz; % MRSI voxels
            [Nx,Ny,~] = size(app.MRIReg_element.Brain_mask);
            current_slice = app.Slices_number(str2double(app.MRSI_Slice_DropDown.Value)); % current MRI slice
            range_slices = current_slice-app.slice_range:current_slice+app.slice_range; % range of slices studied
            
            side = table2array(app.t_custom_regions(:,3)); % Which side for each region
            labels_slice = zeros(Nx,Ny);
            for idx_region = 1:size(app.t_custom_regions,1)
                if table2array(app.t_custom_regions(idx_region,1)) % Check if added region
                    idx_side = find(strcmp(char(side(idx_region)),{'Left','Right','L+R'})); % 1:Left, 2:Right, 3+:L+R
                    if idx_side == 1 % Left
                        label_slice_l = app.MRIReg_element.Segmentation(idx_region).Left;
                        labels_slice = labels_slice + squeeze(sum(label_slice_l(:,:,range_slices),3));
                    elseif idx_side == 2 % Right
                        label_slice_r = app.MRIReg_element.Segmentation(idx_region).Right;
                        labels_slice = labels_slice + squeeze(sum(label_slice_r(:,:,range_slices),3));
                    else % L+R
                        label_slice_l = app.MRIReg_element.Segmentation(idx_region).Left;
                        label_slice_l = squeeze(sum(label_slice_l(:,:,range_slices),3));
                        label_slice_r = app.MRIReg_element.Segmentation(idx_region).Right;
                        label_slice_r = squeeze(sum(label_slice_r(:,:,range_slices),3));
                        labels_slice = labels_slice + label_slice_l + label_slice_r;
                    end
                end
            end
            % Transformation from Nx x Ny to MatSize(1) x MatSize(2)
            % mask
            labels_slice = double(logical(labels_slice));
            super_image = kron(labels_slice,ones(MatSize));
            R = reshape(super_image, Nx, MatSize(1), Ny, MatSize(2));
            S = sum(sum(R,1),3)/(Nx * Ny);
            final_slice = reshape(S, MatSize(1), MatSize(2));
            app.current_mask = (final_slice > app.overlap);

            app.display_MRSI() % call the display MRSI function
        end
        
        %% SVS/DWS public function
        % Loading SVS/DWS datasets from Bruker experiments
        function msg = SVS_DWS_load(app,folderexp,expnb_data,names_data)
            msg = {''};
            % Pre loading situation
            if strcmp(app.Spec_calc,'SVS')
                current_folderexp = {app.struct_svs_exp.folder};
                current_expnb = [app.struct_svs_exp.expnb];
                current_names_data = {app.struct_svs_exp.exp_name};
                l = length(app.struct_svs_exp);
            elseif strcmp(app.Spec_calc,'DWS')
                current_folderexp = {app.struct_dws_exp.folder};
                current_expnb = [app.struct_dws_exp.expnb];
                current_names_data = {app.struct_dws_exp.exp_name};
                l = length(app.struct_dws_exp);
            end
            for e = 1 : length(folderexp)
                add_struct = 0;
                if ~any(strcmp(current_names_data,names_data{e}))
                    idx_folder = find(strcmp(current_folderexp,folderexp{e}));
                    add_struct = 1;
                    if ~isempty(idx_folder)
                        for j = 1 : length(idx_folder)
                            if current_expnb(idx_folder(j)) == expnb_data(e)
                                add_struct = 0;
                                msg = {'Not all the experiment have been uploaded'};
                                break;
                            end
                        end
                    end
                else
                    msg = {'Not all the experiment have been uploaded'};
                end
                if add_struct % Add new experiment to the SVS struct
                    if strcmp(app.Spec_calc,'SVS')
                        app.struct_svs_exp(l+1).folder = folderexp{e};
                        app.struct_svs_exp(l+1).expnb = expnb_data(e);
                        app.struct_svs_exp(l+1).exp_name = names_data{e};
                        app.struct_svs_exp(l+1).ref_bool = false;
                        app.struct_svs_exp(l+1).met_bool = false;
                    elseif strcmp(app.Spec_calc,'DWS')
                        app.struct_dws_exp(l+1).folder = folderexp{e};
                        app.struct_dws_exp(l+1).expnb = expnb_data(e);
                        app.struct_dws_exp(l+1).exp_name = names_data{e};
                        app.struct_dws_exp(l+1).ref_bool = false;
                        app.struct_dws_exp(l+1).met_bool = false;
                    end
                    l = l + 1;
                end
            end           
            app.SVS_DWS_experiments();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % App path
            [app.app_path,~] = fileparts(mfilename('fullpath'));
            % Cookies
            app.init_app();
            % UITable
            app.init_table();
            % MRSI
            temp = load(fullfile(app.app_path,'Others','MRSI_parameters.mat'),'mrsi_param');
            app.mrsi_param = temp.mrsi_param;
            % MRI registration
            temp = load(fullfile(app.app_path,'Others','MRIReg_parameters.mat'),'mrireg_param');
            app.reg_param = temp.mrireg_param;
            % SVS
            temp = load(fullfile(app.app_path,'Others','SVS_parameters.mat'),'svs_param');
            app.svs_param = temp.svs_param;
            % DWS
            temp = load(fullfile(app.app_path,'Others','DWS_parameters.mat'),'dws_param');
            app.dws_param = temp.dws_param;
            % Display options
            temp = load(fullfile(app.app_path,'Others','Display_options.mat'),'disp_opt');
            app.disp_opt = temp.disp_opt;
            
            % Colorbar
            app.clrbar = colorbar(app.UIAxes_MRSI_2,'Location','manual');
            colormap(app.UIAxes_MRSI_2,"jet")
            app.clrbar.Position = [0.9, 0.24, 0.025, 0.71];
            app.clrbar.Label.String = sprintf('Concentration /%s',app.mrsi_param.Rel_met);
            
            % MRSI UIAxes
            disableDefaultInteractivity(app.UIAxes_MRSI_1)
            disableDefaultInteractivity(app.UIAxes_MRSI_2)
        end

        % Button pushed function: DWS_Button, MRSI_Button, SVS_Button
        function TabSelection_ButtonPushed(app, event)
            if strcmp(event.Source.Text,'MRSI')
                app.TabGroup.SelectedTab = app.MRSITab;
                app.Spec_calc = 'MRSI';
            elseif strcmp(event.Source.Text,'SVS')
                app.TabGroup.SelectedTab = app.SVSTab;
                app.Spec_calc = 'SVS';
            elseif strcmp(event.Source.Text,'DWS')
                app.TabGroup.SelectedTab = app.DWSTab;
                app.Spec_calc = 'DWS';
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            title_tab = app.TabGroup.SelectedTab.Title;
            if strcmp(title_tab,'MRSI')
                app.Spec_calc = 'MRSI';
            elseif strcmp(title_tab,'SVS')
                app.Spec_calc = 'SVS';
            elseif strcmp(title_tab,'DWS')
                app.Spec_calc = 'DWS';
            end
        end

        % Button pushed function: MRSI_Choose_path_Button
        function MRSI_Choose_path_ButtonPushed(app, event)
            % Choose a path for the Bruker data folder, change the
            % Studyname if empty
            dir = uigetdir(app.folder_dir,"Choose a directory");
            figure(app.UIFigure)
            if dir ~= 0
                if isfolder(dir)
                    app.folder_dir = dir;
                    app.MRSI_check_directory;
                    app.MRSI_Open_pdfdoc_Button.Enable = 'on';
                    [x,y,~] = fileparts(dir);
                    if isempty(app.study_name)
                        app.MRSI_Studyname_EditField.Value = y;
                        app.study_name = y;
                    end
                    % Save the directory in the cookies
                    temp = load(fullfile(app.app_path,'Others','Cookies.mat'),'T');
                    T = temp.T;
                    T.data_folder = x;
                    save(fullfile(app.app_path,'Others','Cookies.mat'),'T');
                end
            end
        end

        % Button pushed function: MRSI_result_folder_Button
        function MRSI_result_folder_ButtonPushed(app, event)
            % Choose a directory for the result folder
            dir = uigetdir(app.result_dir,"Choose a directory");
            figure(app.UIFigure)
            if dir ~= 0
                if isfolder(dir)
                    app.result_dir = dir;
                end
                % Save the directory in the cookies
                temp = load(fullfile(app.app_path,'Others','Cookies.mat'),'T');
                T = temp.T;
                T.results_folder = dir;
                save(fullfile(app.app_path,'Others','Cookies.mat'),'T');
            end
        end

        % Button pushed function: MRSI_Studyname_Button
        function MRSI_Studyname_ButtonPushed(app, event)
            % Choose a Study name folder that already exist
            dir = uigetdir(app.result_dir,"Choose a directory");
            figure(app.UIFigure)
            if dir ~= 0
                if isfolder(dir)
                    [x,y,~] = fileparts(dir);
                    app.result_dir = x;
                    app.MRSI_Studyname_EditField.Value = y;
                    app.study_name = y;
                end
            end
        end

        % Value changed function: MRSI_Studyname_EditField
        function MRSI_Studyname_EditFieldValueChanged(app, event)
            app.study_name = app.MRSI_Studyname_EditField.Value;
        end

        % Button pushed function: MRSI_Open_pdfdoc_Button
        function MRSI_Open_pdfdoc_ButtonPushed(app, event)
            % Open the scan notes in the Bruker data folder
            [file, path] = uigetfile({'*.pdf','Scan notes'}, ...
                "Select the scan notes", ...
                app.folder_dir);
            figure(app.UIFigure)
            if file ~= 0
                open(fullfile(path,file));
            end
        end

        % Value changed function: MRSI_FillgapsButton
        function MRSI_FillgapsButtonValueChanged(app, event)
            % Apply Fillgaps on data
            value = app.MRSI_FillgapsButton.Value;
            if value
                app.MRSI_LipidsupButton.Value = 1;
            end
        end

        % Value changed function: MRSI_Ref_CheckBox
        function MRSI_Ref_CheckBoxValueChanged(app, event)
            value = app.MRSI_Ref_CheckBox.Value;
            if value
                app.MRSI_Ref_EditField.Enable = 'on';
            else
                app.MRSI_Ref_EditField.Enable = 'off';
            end
        end

        % Callback function: MRSI_Image_EditField, MRSI_MRISlice_UITable, 
        % ...and 3 other components
        function MRSI_Data_specific_ValueChanged(app, event)
            % Update the UITable and the properties (image_fn, metab_fn,
            % ref_fn, Slice_range and Slices_number)
            app.slice_range = app.MRSI_Slice_range_EditField.Value;
            if or(app.MRSI_Met_EditField.Value == app.MRSI_Ref_EditField.Value, ...
                or(app.MRSI_Met_EditField.Value == app.MRSI_Image_EditField.Value, ...
                app.MRSI_Ref_EditField.Value == app.MRSI_Image_EditField.Value))
                app.MRSI_Image_EditField.Value = app.image_fn;
                app.MRSI_Met_EditField.Value = app.metab_fn;
                app.MRSI_Ref_EditField.Value = app.ref_fn;
                app.MRSI_RecoNb_EditField.Value = app.reco_fn;
                return
            end
            if or(app.MRSI_Met_EditField.Value > app.max_folder_number, ...
                    or(app.MRSI_Ref_EditField.Value > app.max_folder_number, ...
                    app.MRSI_Image_EditField.Value > app.max_folder_number))
                app.MRSI_Image_EditField.Value = app.image_fn;
                app.MRSI_Met_EditField.Value = app.metab_fn;
                app.MRSI_Ref_EditField.Value = app.ref_fn;
                app.MRSI_RecoNb_EditField.Value = app.reco_fn;
                return
            end
            if app.N_slices == 1 
                app.image_fn = app.MRSI_Image_EditField.Value;
                app.metab_fn = app.MRSI_Met_EditField.Value;
                app.ref_fn = app.MRSI_Ref_EditField.Value;
                app.reco_fn = app.MRSI_RecoNb_EditField.Value;
                app.Slices_number = app.MRSI_MRISlice_UITable.Data{1,2};
            else
                app.image_fn = app.MRSI_Image_EditField.Value;
                app.metab_fn = app.MRSI_Met_EditField.Value;
                app.ref_fn = app.MRSI_Ref_EditField.Value;
                app.reco_fn = app.MRSI_RecoNb_EditField.Value;
                sc = [app.MRSI_MRISlice_UITable.Data{1:end,2}];
                if length(sc) == length(unique(sc))
                    app.Slices_number = sc;
                else
                    for i = 1:app.N_slices
                        app.MRSI_MRISlice_UITable.Data{i,2} = app.Slices_number(i);
                    end
                end
            end
        end

        % Value changed function: MRSI_Number_slices_Spinner
        function MRSI_Number_slices_SpinnerValueChanged(app, event)
            % Update the number of slices, add or remove position of the slices
            value = app.MRSI_Number_slices_Spinner.Value;
            if and(value > 1,app.N_slices == 1)
                app.Slices_number = [app.Slices_number(1), app.Slices_number(1) + 1];
                app.MRSI_MRISlice_UITable.Data = [{'MRI central slice 1';'MRI central slice 2'}, ...
                    {app.Slices_number(1); app.Slices_number(2)}];
                app.MRSI_Slice_DropDown.Items = string(1:value);
                app.MRSI_Slice_DropDown.Value = '1';
            elseif and(value > 1, app.N_slices > 1)
                if value > app.N_slices
                    for j = app.N_slices+1:value
                        app.Slices_number(j) = app.Slices_number(j-1) + 1;
                        app.MRSI_MRISlice_UITable.Data{j,1} = ['MRI central slice ' num2str(j)];
                        app.MRSI_MRISlice_UITable.Data{j,2} = app.Slices_number(j);
                    end
                else
                    app.Slices_number = app.Slices_number(1:value);
                    app.MRSI_MRISlice_UITable.Data = app.MRSI_MRISlice_UITable.Data(1:value,:);
                end
                app.MRSI_Slice_DropDown.Items = string(1:value);
            else
                app.Slices_number = app.Slices_number(1);
                app.MRSI_MRISlice_UITable.Data = [{'MRI central slice 1'},{app.Slices_number}];
                app.MRSI_Slice_DropDown.Items = string(1:value);
            end
            app.N_slices = value;
        end

        % Button pushed function: MRSI_Analysis_Button
        function MRSI_Analysis_ButtonPushed(app, event)
            % Callback to analyse the MRSI data (MRI and then MRS)
            if and(isempty(app.result_dir),isempty(app.MRSI_Studyname_EditField.Value))
                uialert(app.UIFigure,'Please choose a result directory and a study name', ...
                    'MRSI analysis','Icon','warning')
                return
            end
            % Do the image registration and segmentation + labels
            % translation
            app.MRSI_done_bool = false;
            ok = app.MRI_Registration();
            pause(0.5);
            if ok
               app.MRSI_Volumetry_Button.Enable = 'on';
                % Do the spectroscopy processing
                ok = app.MRSI_analysis();
                if ok 
                    app.original_region = 1;
                    app.MRSI_MetaboliteDropDown.Items = ['Current mask';'Power Map';'SNR map';'Linewidth map';app.MRSI_experiment.Met_names];
                    app.MRSI_MetaboliteDropDown.Enable = 'on';
                    app.MRSI_conc_table_Button.Enable = 'on';
                    app.MRSI_Voxel_selection_Button.Enable = 'on';
                    app.MRSI_RegionDropDown.Enable = 'on';
                    app.MRSI_RegionDropDown.Value = 'Whole Brain';
                    app.region_name = 'Whole Brain';
                    app.MRSI_SideDropDown.Enable = 'on';
                    app.MRSI_Spectra_Button.Enable = 'on';
                    app.MRSI_Save_map_Button.Enable = 'on';
                    app.MRSI_CustomButton.Enable = 'on';
                    app.MRSI_CustomButton.Visible = 'off';
                    app.MRSI_CustomregionsCheckBox.Enable = 'on';
                                app.MRSI_Job0CheckBox.Enable = 'on';
                    app.MRSI_CustomregionsCheckBox.Value = 0;
                    app.MRSI_Job0CheckBox.Value = 0;
                    app.MRSI_done_bool = true;
                    app.display_MRSI();
                else
                    uialert(app.UIFigure,['Error in the spectroscopy processing,' ...
                        ' please check your data files'],'MRSI analysis');
                    app.MRSI_MetaboliteDropDown.Enable = 'off';
                    app.MRSI_conc_table_Button.Enable = 'off';
                    app.MRSI_Voxel_selection_Button.Enable = 'off';
                    app.MRSI_Spectra_Button.Enable = 'off';
                    app.MRSI_RegionDropDown.Enable = 'off';
                    app.MRSI_SideDropDown.Enable = 'off';
                    app.MRSI_Save_map_Button.Enable = 'off';
                    app.MRSI_CustomregionsCheckBox.Enable = 'off';
                    app.MRSI_Job0CheckBox.Enable = 'off';
                    app.MRSI_CustomButton.Enable = 'off';
                end
            else
                app.MRSI_RegionDropDown.Enable = 'off';
                app.MRSI_SideDropDown.Enable = 'off';
                app.MRSI_Voxel_selection_Button.Enable = 'off';
                app.MRSI_Spectra_Button.Enable = 'off';
                app.MRSI_MetaboliteDropDown.Enable = 'off';
                app.MRSI_conc_table_Button.Enable = 'off';
                app.MRSI_Save_map_Button.Enable = 'off';
                app.MRSI_Volumetry_Button.Enable = 'off';
                app.MRSI_CustomregionsCheckBox.Enable = 'off';
                app.MRSI_Job0CheckBox.Enable = 'off';
                app.MRSI_CustomButton.Enable = 'off';
                uialert(app.UIFigure,['Error in the image processing, ' ...
                    'please check your data files'],'MRSI Analysis')
            end
        end

        % Button pushed function: MRSI_Preferences_Button
        function MRSI_Preferences_ButtonPushed(app, event)
            % Open the Preferences GUI
            app.PreferencesApp = preferences_MRSI(app);
        end

        % Button pushed function: MRSI_Statistics_Button
        function MRSI_Statistics_ButtonPushed(app, event)
            statistics_MRSI(app.result_dir)
        end

        % Value changed function: MRSI_ConcentrationsDropDown
        function MRSI_ConcentrationsDropDownValueChanged(app, event)
            % Choose relative or absolute concentration
            if strcmp(app.MRSI_ConcentrationsDropDown.Value,'Relative')
                app.clrbar.Label.String = sprintf('Concentration /%s',app.mrsi_param.Rel_met);
            elseif strcmp(app.MRSI_ConcentrationsDropDown.Value,'CRLB')
                app.clrbar.Label.String = 'CRLB';
            else
                app.clrbar.Label.String = 'Concentration [mmol/l]';
            end
            if app.max_steps >= 9
                app.display_MRSI();
            end
        end

        % Value changed function: MRSI_MetaboliteDropDown
        function MRSI_MetaboliteDropDownValueChanged(app, event)
            % Change the displayed metabolite
            app.display_MRSI()
        end

        % Value changed function: MRSI_Slice_DropDown
        function MRSI_Slice_DropDownValueChanged(app, event)
            index = str2double(app.MRSI_Slice_DropDown.Value);
            if ~isempty(app.MRIReg_element)
                if ~isempty(app.MRSI_experiment)
                    if ~isempty(app.MRSI_experiment.Final_met_map(index))
                        app.display_MRSI()
                    else
                        uialert(app.UIFigure,'No existing metabolite map', ...
                            'Show Results','Icon','warning')
                    end
                end
            end
        end

        % Value changed function: MRSI_CustomregionsCheckBox
        function MRSI_CustomregionsCheckBoxValueChanged(app, event)
            value = app.MRSI_CustomregionsCheckBox.Value;
            if value
                app.MRSI_RegionDropDown.Visible = 'off';
                app.MRSI_SideDropDown.Enable = 'off';
                app.MRSI_CustomButton.Visible = 'on';
                app.region_name = app.MRSI_RegionDropDown.Value;
            else
                app.MRSI_RegionDropDown.Visible = 'on';
                app.MRSI_SideDropDown.Enable = 'on';
                app.MRSI_CustomButton.Visible = 'off';
                app.region_name = 'Custom_region';
            end
        end

        % Value changed function: MRSI_RegionDropDown, MRSI_SideDropDown
        function MRSI_Region_Side_ValueChanged(app, event)
            app.region_name = regexprep(app.MRSI_RegionDropDown.Value,' ','_');
            app.MRSI_display_region();
        end

        % Button pushed function: MRSI_Voxel_selection_Button
        function MRSI_Voxel_selection_ButtonPushed(app, event)
            % MRI
            try
                Slice_index = str2double(app.MRSI_Slice_DropDown.Value);
                min_slice = app.Slices_number(Slice_index) - app.slice_range;
                max_slice = app.Slices_number(Slice_index) + app.slice_range;
                Image_slice = rot90(squeeze(sum(double(app.MRIReg_element.original_image(:,:,min_slice:max_slice)),3)),-1);
                Image_slice = uint8(Image_slice/max(max(Image_slice))*256);
                
                if app.MRSI_CustomregionsCheckBox.Value
                    name_region = 'custom_region';
                else
                    name_region = [app.MRSI_RegionDropDown.Value,'_', ...
                        app.MRSI_SideDropDown.Value];
                end
                app.VoxelSelectionApp = voxel_selection_MRSI(app,Image_slice,...
                    app.current_mask,app.MRSI_Slice_DropDown.Value,name_region);
                app.MRSI_Voxel_selection_Button.Enable = 'off';
            catch ME
                uialert(app.UIFigure,ME.message,'Voxel selection');
                app.MRSI_Voxel_selection_Button.Enable = 'on';
            end
        end

        % Button pushed function: MRSI_CustomButton
        function MRSI_CustomButtonPushed(app, event)
            try
                app.CustomRegionsApp = custom_regions_MRSI(app);
            catch ME
                uialert(app.UIFigure,ME.message,'Custom regions')
            end
        end

        % Value changed function: MRSI_OverlapSlider
        function MRSI_OverlapSliderValueChanged(app, event)
            app.overlap = app.MRSI_OverlapSlider.Value;
            app.MRSI_Overlap_EditField.Value = app.overlap;
            if app.max_steps >= 11
                if app.MRSI_CustomregionsCheckBox.Value
                    app.MRSI_display_custom_regions();
                else
                    app.MRSI_display_region();
                end
            end
        end

        % Value changed function: MRSI_Overlap_EditField
        function MRSI_Overlap_EditFieldValueChanged(app, event)
            app.overlap = app.MRSI_Overlap_EditField.Value;
            app.MRSI_OverlapSlider.Value = app.overlap;
            if app.max_steps >= 11
                if app.MRSI_CustomregionsCheckBox.Value
                    app.MRSI_display_custom_regions();
                else
                    app.MRSI_display_region();
                end
            end
        end

        % Button pushed function: MRSI_Volumetry_Button
        function MRSI_Volumetry_ButtonPushed(app, event)
            Volumetry = app.MRIReg_element.Volumetry;
            save_dir = fullfile(app.result_dir,app.study_name, ...
                ['Slice_N' app.MRSI_Slice_DropDown.Value],'Results');
            app.VolumetryTableApp = volumetry_table_MRSI(app,Volumetry,save_dir);
            app.MRSI_Spectra_Button.Enable = 'on';
        end

        % Button pushed function: MRSI_Spectra_Button
        function MRSI_Spectra_ButtonPushed(app, event)
            % MRI
            try
                Slice_index = str2double(app.MRSI_Slice_DropDown.Value);
                min_slice = app.Slices_number(Slice_index) - app.slice_range;
                max_slice = app.Slices_number(Slice_index) + app.slice_range;
                Image_slice = rot90(squeeze(sum(double(app.MRIReg_element.original_image(:,:,min_slice:max_slice)),3)),-1);
                Image_slice = uint8(Image_slice/max(max(Image_slice))*256);
                app.MRSI_Spectra_Button.Enable = 'off';
                prog_d = uiprogressdlg(app.UIFigure,"Title",'Spectra plots','Message',  ...
                        'Showing spectra of each MRSI voxel','Icon','none','Indeterminate','on');
                app.SpectraplotApp = plot_spectra_MRSI(app,app.MRSI_experiment, ...
                    Image_slice,app.MRSI_Slice_DropDown.Value,prog_d);
                waitfor(app.SpectraplotApp)
            catch ME
                delete(prog_d)
                uialert(app.UIFigure,ME.message,'Spectra plot');
                app.MRSI_Spectra_Button.Enable = 'on';
            end
        end

        % Button pushed function: MRSI_conc_table_Button
        function MRSI_conc_table_ButtonPushed(app, event)
            results = app.MRSI_calculate_conc();
            save_dir = fullfile(app.result_dir,app.study_name, ...
                ['Slice_N' app.MRSI_Slice_DropDown.Value],'Results');
            conc_type = app.MRSI_ConcentrationsDropDown.Value;
            app.ConcTableApp = conc_table_MRSI(app,results,conc_type,save_dir);
        end

        % Button pushed function: MRSI_Save_map_Button
        function MRSI_Save_map_ButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,"Title",'Save image','Message', ...
                'Saving current displayed image in the result folder','Indeterminate','on');
            fs = figure('Visible','off'); % on to be visible
            % MRSI Matrix parameter
            Matrix_Size = app.MRSI_experiment.acq_params.matrix_sz;
            Met_index = find(strcmp(app.MRSI_MetaboliteDropDown.Items, app.MRSI_MetaboliteDropDown.Value));
            if length(Met_index) > 1 % Prevent 2 or more metabolites with the same name
                Met_index = Met_index(1);
            end
            mask_plot = 0; % Plot the current mask instead of metabolites
            power_map_plot = 0; % Plot the power map
            SNR_plot = 0;
            lw_plot = 0;
            if Met_index == 1
                mask_plot = 1;
            elseif Met_index == 2
                power_map_plot = 1;
            else
                Met_index = Met_index - 2;
            end
            Slice_index = str2double(app.MRSI_Slice_DropDown.Value);

            % MRI
            min_slice = app.Slices_number(Slice_index) - app.slice_range;
            max_slice = app.Slices_number(Slice_index) + app.slice_range;
            Image_slice = rot90(squeeze(sum(double(app.MRIReg_element.original_image(:,:,min_slice:max_slice)),3)),-1);
            Image_slice = uint8(Image_slice/max(max(Image_slice))*256);
            Image_Size = size(Image_slice);

            % MRSI
            if ~mask_plot && ~power_map_plot && ~SNR_plot && ~lw_plot
                if strcmp(app.MRSI_ConcentrationsDropDown.Value,'Relative')
                    if app.MRSI_Job0CheckBox.Value
                        map = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).rel_conc(Met_index,:,:)); 
                    else
                        map = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).rel_conc(Met_index,end:-1:1,:)); 
                        % Mirror on the lines (BECAUSE OF THE H-F ENCODING ORIENTATION)
                    end

                    if app.mri_headprone
                        map = map(end:-1:1,:);
                    end
                else
                    if app.MRSI_Job0CheckBox.Value
                        map = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).abs_conc(Met_index,:,:));
                    else
                        map = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).abs_conc(Met_index,end:-1:1,:)); 
                        % Mirror on the lines (BECAUSE OF THE H-F ENCODING ORIENTATION)
                    end

                    if app.mri_headprone
                        map = map(end:-1:1,:);
                    end
                end
    
                % Current, SNR and FWHM masks
                if app.MRSI_Job0CheckBox.Value
                    CRLBs = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).CRB(Met_index,:,:));
                else
                    CRLBs = squeeze(app.MRSI_experiment.Final_met_map(Slice_index).CRB(Met_index,end:-1:1,:));
                end

                if app.mri_headprone
                    CRLBs = CRLBs(end:-1:1,:);
                end
                CRLBs_mask = and(CRLBs < app.disp_opt.CRLB_limit/100, CRLBs ~= 0);

                if app.disp_opt.LCModel_qc
                    if app.MRSI_Job0CheckBox.Value
                        SNR_map = app.MRSI_experiment.Final_met_map(Slice_index).SNR(:,:);
                        FWHM_map = app.MRSI_experiment.Final_met_map(Slice_index).FWHM(:,:);
                    else
                        SNR_map = app.MRSI_experiment.Final_met_map(Slice_index).SNR(end:-1:1,:);
                        FWHM_map = app.MRSI_experiment.Final_met_map(Slice_index).FWHM(end:-1:1,:);
                    end

                    if app.mri_headprone
                        SNR_map = SNR_map(end:-1:1,:);
                        FWHM_map = FWHM_map(end:-1:1,:);
                    end
                else
                    if app.MRSI_Job0CheckBox.Value
                        SNR_map = squeeze(app.MRSI_experiment.SNR_map(Slice_index,:,:));
                        FWHM_map = squeeze(app.MRSI_experiment.Linewidth_map(Slice_index,:,:));
                    else
                        SNR_map = squeeze(app.MRSI_experiment.SNR_map(Slice_index,end:-1:1,:));
                        FWHM_map = squeeze(app.MRSI_experiment.Linewidth_map(Slice_index,end:-1:1,:));
                    end

                    if app.mri_headprone
                        SNR_map = SNR_map(end:-1:1,:);
                        FWHM_map = FWHM_map(end:-1:1,:);
                    end
                end

                mean_SNR = mean(SNR_map(SNR_map > 0));
                mean_FWHM = mean(FWHM_map(FWHM_map > 0));
                if app.disp_opt.SNR_qc % SNR quality control
                    if app.disp_opt.SNR_th_bool
                        SNR_mask = SNR_map > app.disp_opt.SNR_min_th*mean_SNR;
                    else
                        SNR_mask = SNR_map >= app.disp_opt.SNR_min_val;
                    end
                else
                    SNR_mask = true(size(SNR_map));
                end
                if app.disp_opt.FWHM_qc % FWHM quality control
                    if app.disp_opt.FWHM_th_bool
                        FWHM_mask = FWHM_map < app.disp_opt.FWHM_max_th*mean_FWHM;
                    else
                        FWHM_mask = FWHM_map < app.disp_opt.FWHM_max_val;
                    end
                else
                    FWHM_mask = true(size(FWHM_map));
                end
                map_total = map .* CRLBs_mask .* SNR_mask .* FWHM_mask;
                map_mask = logical(app.current_mask) .* CRLBs_mask .* SNR_mask .* FWHM_mask;
    
                max_map = max(max(map_total));
    
                % Interpolation of the map
                if app.disp_opt.interpol_bool
                    [X,Y] = meshgrid(1:Matrix_Size(1),1:Matrix_Size(2));
                    [XI,YI] = meshgrid(1:(Matrix_Size(1)-1)/(Image_Size(1)-1):Matrix_Size(1), ...
                        1:(Matrix_Size(2)-1)/(Image_Size(2)-1):Matrix_Size(2));
                    lin = interp2(X,Y,map_total,XI,YI,'cubic');
                    BA = lin;
                    lin2 = interp2(X,Y,double(map_mask),XI,YI,'cubic');
                    CC = lin2;
                    CC = CC > 0.7;
                    BA = BA .* CC;
                else
                    BA = map_total .* logical(app.current_mask);
                end
            elseif ~power_map_plot && ~SNR_plot && ~lw_plot
                BA = double(squeeze(app.MRSI_experiment.Final_mask(Slice_index,:,:)));
            elseif ~SNR_plot && ~lw_plot
                if app.MRSI_Job0CheckBox.Value
                    BA = double(squeeze(app.MRSI_experiment.Power_map(Slice_index,:,:)));
                else
                    BA = double(squeeze(app.MRSI_experiment.Power_map(Slice_index,end:-1:1,:)));
                end

                if app.mri_headprone
                    BA = BA(end:-1:1,:);
                end
            elseif ~lw_plot
                if app.MRSI_Job0CheckBox.Value
                    BA = double(squeeze(app.MRSI_experiment.SNR_map(Slice_index,:,:)).*squeeze(app.MRSI_experiment.Final_mask(Slice_index,:,:)));
                else
                    BA = double(squeeze(app.MRSI_experiment.SNR_map(Slice_index,end:-1:1,:)).*squeeze(app.MRSI_experiment.Final_mask(Slice_index,:,:)));
                end

                if app.mri_headprone
                    BA = BA(end:-1:1,:);
                end
            else
                if app.MRSI_Job0CheckBox.Value
                    BA = double(squeeze(app.MRSI_experiment.Linewidth_map(Slice_index,:,:)).*squeeze(app.MRSI_experiment.Final_mask(Slice_index,:,:)));
                else
                    BA = double(squeeze(app.MRSI_experiment.Linewidth_map(Slice_index,end:-1:1,:)).*squeeze(app.MRSI_experiment.Final_mask(Slice_index,:,:)));
                end

                if app.mri_headprone
                    BA = BA(end:-1:1,:);
                end
            end


            % Plot current slice
            ax1 = axes(fs);
            ax1.Position = [0.1,0.1,0.8,0.8];
            im_MRI = imagesc(ax1,Image_slice);
            im_MRI.AlphaData = 1; % change this value to change the background image transparency
            axis(ax1,'square')
            hold all

            % Plot concentration map
            ax2 = axes(fs);
            ax2.Position = [0.1,0.1,0.8,0.8];
            im_MRSI = imagesc(ax2,BA);
            im_MRSI.AlphaData = 0.4; % change this value to change the foreground image transparency
            axis(ax2,'square')

            % Add differenct colormap to different data if you wish
            colormap(ax1,'gray')
            if mask_plot
                mapcolors = [0.15 0.15 0.15; 0.07 0.62 1];
                cmap = [mapcolors(1,:); ...
                    mapcolors(2,:)];
                colormap(ax2,cmap)
            else
                colormap(ax2,'jet')
            end
            
            % Colorbar limits
            if ~mask_plot && ~power_map_plot && ~SNR_plot && ~lw_plot
                if strcmp(app.MRSI_ConcentrationsDropDown.Value,'Relative')
                    if app.disp_opt.auto_max_conc
                        if and(max_map > 0, max_map < 20)
                            clim(ax2,[0 max_map]);
                        else
                            clim(ax2,[0 15]);
                        end
                    else
                        clim(ax2,[0 app.disp_opt.max_conc]);
                    end
                else
                    if and(max_map > 0,max_map < 10000)
                        clim(ax2,[0 max_map]);
                    else
                        clim(ax2,[0 10000]);
                    end
                end
                clrb = colorbar(ax2,'Location','manual');
                clrb.Position = [0.81,0.102,0.04,0.8];
                clrb.FontSize = 14;
                clrb.FontWeight = "bold";
            elseif ~power_map_plot && ~SNR_plot && ~lw_plot
                clim([0,1])
            elseif ~SNR_plot && ~lw_plot 
                max_map = max(max(BA));
                clim([0,max_map])
                clrb = colorbar(ax2,'Location','manual');
                clrb.Position = [0.81,0.102,0.04,0.8];
                clrb.FontSize = 14;
                clrb.FontWeight = "bold";
            elseif ~lw_plot
                clim([0,100])
            else
                clim([0,60])
            end

            % linkaxes([ax1,ax2]) % linking axes together

            % Remove ticks
            ax1.Visible = 'off';
            ax1.XColor = 'none';
            ax1.XTick = [];
            ax1.YColor = 'none';
            ax1.YTick = [];
            ax2.Visible = 'off';
            ax2.XTick = [];
            ax2.YTick = [];
            
            % Figure title and export image concentration type
            if ~mask_plot && ~power_map_plot && ~SNR_plot && ~lw_plot
                if strcmp(app.MRSI_ConcentrationsDropDown.Value,'Relative')
                    title([app.MRSI_MetaboliteDropDown.Value ' relative Metabolite map'],'Visible','on')
                    conc_type = 'rel';
                else
                    title([app.MRSI_MetaboliteDropDown.Value ' absolute Metabolite map'],'Visible','on')
                    conc_type = 'abs';
                end
            elseif ~power_map_plot && ~SNR_plot && ~lw_plot
                title(['Current mask : ' app.region_name ' ' app.MRSI_SideDropDown.Value])
            elseif ~SNR_plot && ~lw_plot
                title('Water Power Map')
            elseif ~lw_plot
                title('SNR Map')
            else
                title('Linewidth Map')
            end

            % Export image name and subtitle
            if app.MRSI_CustomregionsCheckBox.Value
                subtitle_name = 'Combined Regions';
                export_name_region = 'combined_regions';
            else
                subtitle_name = [app.MRSI_SideDropDown.Value ' ' app.MRSI_RegionDropDown.Value ' Region'];
                export_name_region = [app.MRSI_RegionDropDown.Value,'_',app.MRSI_SideDropDown.Value];
            end
            folder_export = fullfile(app.result_dir,app.study_name, ...
                ['Slice_N' app.MRSI_Slice_DropDown.Value],'Results');
            if ~mask_plot && ~power_map_plot
                if app.original_region
                    subtitle(subtitle_name,'Visible','on')
                    export_name = fullfile(folder_export, ...
                        [app.MRSI_MetaboliteDropDown.Value,'_',conc_type,'_', ...
                        export_name_region,'.png']);
                else
                    subtitle([subtitle_name ' modified'],'Visible','on')
                    export_name = fullfile(folder_export, ...
                        [app.MRSI_MetaboliteDropDown.Value,'_',conc_type,'_', ...
                        export_name_region,'_mod.png']);
                end
            elseif ~power_map_plot
                export_name = fullfile(folder_export, ...
                    ['Current_mask_' regexprep(app.region_name,' ','_') '.png']);
            else
                export_name = fullfile(folder_export, ...
                    ['Water_Power_Map' regexprep(app.region_name,' ','_') '.png']);
            end

            if ~isfolder(fullfile(app.result_dir,app.study_name, ...
                    ['Slice_N' app.MRSI_Slice_DropDown.Value],'Results'))
                mkdir(fullfile(app.result_dir,app.study_name, ...
                    ['Slice_N' app.MRSI_Slice_DropDown.Value],'Results'))
            end

            if isfile(export_name)
                fn = export_name(1:end-4);
                n_fn = 2;
                while isfile([fn '_' num2str(n_fn) '.png'])
                    n_fn = n_fn + 1;
                end
                export_name = [fn '_' num2str(n_fn) '.png'];
            end

            exportgraphics(fs,export_name,'Resolution',600);
            close(d)
            figure(app.UIFigure)
            close(fs)
        end

        % Button pushed function: MRSI_Display_additional_Button
        function MRSI_Display_additional_ButtonPushed(app, event)
            app.DisplayOptionsApp = display_options_MRSI(app);
        end

        % Button pushed function: SVS_result_folder_Button
        function SVS_result_folder_ButtonPushed(app, event)
            % Choose a directory for the result folder
            dir = uigetdir(app.result_dir,"Choose a directory");
            figure(app.UIFigure)
            if dir ~= 0
                if isfolder(dir)
                    app.result_dir = dir;
                end
                % Save the directory in the cookies
                temp = load(fullfile(app.app_path,'Others','Cookies.mat'),'T');
                T = temp.T;
                T.results_folder = dir;
                save(fullfile(app.app_path,'Others','Cookies.mat'),'T');
            end
        end

        % Button pushed function: SVS_Studyname_Button
        function SVS_Studyname_ButtonPushed(app, event)
            % Choose a Study name folder that already exist
            dir = uigetdir(app.result_dir,"Choose a directory");
            figure(app.UIFigure)
            if dir ~= 0
                if isfolder(dir)
                    [x,y,~] = fileparts(dir);
                    app.result_dir = x;
                    app.SVS_StudynameEditField.Value = y;
                    app.study_name = y;
                    app.SVS_check_study_folder()
                end
            end
        end

        % Value changed function: SVS_StudynameEditField
        function SVS_StudynameEditFieldValueChanged(app, event)
            app.study_name = app.SVS_StudynameEditField.Value;
            app.SVS_check_study_folder()
        end

        % Button pushed function: Load_SVS_data_Button
        function SVS_Load_data_ButtonPushed(app, event)
            if isempty(app.struct_svs_exp)
                app.struct_svs_exp = struct('folder',[],'expnb',[],'exp_name',[],'ref_bool',[],'met_bool',[]);
                app.struct_svs_exp(1) = [];
            end
            app.SVSDWSLoadApp = load_SVS_DWS(app);
        end

        % Value changed function: Ref_data_SVS_DropDown
        function SVS_Ref_data_DropDownValueChanged(app, event)
            % Update Reference Dropdown and bool
            value = app.Ref_data_SVS_DropDown.Value;
            expnames = {app.struct_svs_exp.exp_name};
            idx_ref = find(strcmp(expnames,value));
            idx_pre_ref = find([app.struct_svs_exp.ref_bool]);
            if ~isempty(idx_ref)
                if ~isempty(idx_pre_ref)
                    app.struct_svs_exp(idx_pre_ref).ref_bool = false;
                end
                app.struct_svs_exp(idx_ref).ref_bool = true;
                app.struct_svs_exp(idx_ref).met_bool = false;
                % Update UITable
                idx_met = find(~[app.struct_svs_exp.ref_bool]);
                State = [app.struct_svs_exp.met_bool];
                table_met = table(State(idx_met).',expnames(idx_met).','VariableNames',{'On/Off','Experiment name'});
                app.UITable_met_SVS.Data = table_met;
            elseif ~isempty(idx_pre_ref)
                app.struct_svs_exp(idx_pre_ref).ref_bool = false;
                % Update UITable
                idx_met = find(~[app.struct_svs_exp.ref_bool]);
                State = [app.struct_svs_exp.met_bool];
                table_met = table(State(idx_met).',expnames(idx_met).','VariableNames',{'On/Off','Experiment name'});
                app.UITable_met_SVS.Data = table_met;
            end
        end

        % Cell edit callback: UITable_met_SVS
        function SVS_UITable_met_CellEdit(app, event)
            if ~isempty(app.struct_svs_exp)
                indices = event.Indices(1);
                t_data = app.UITable_met_SVS.Data{:,2};
                expname_met = t_data{indices(1)};
                idx_met_upd = find(strcmp({app.struct_svs_exp.exp_name},expname_met));
                app.struct_svs_exp(idx_met_upd).met_bool = not(app.struct_svs_exp(idx_met_upd).met_bool);
            end
        end

        % Button pushed function: SVS_Select_all_Button
        function SVS_Select_all_ButtonPushed(app, event)
            if ~isempty(app.struct_svs_exp)
                idx_met = find(~[app.struct_svs_exp.ref_bool]);
                for i = 1:length(idx_met)
                    app.struct_svs_exp(idx_met(i)).met_bool = true;
                end
                t_data = app.UITable_met_SVS.Data;
                t_data{:,1} = true(size(t_data,1),1);
                app.UITable_met_SVS.Data = t_data;
            end
        end

        % Button pushed function: SVS_Deselect_all_Button
        function SVS_Deselect_all_ButtonPushed(app, event)
            if ~isempty(app.struct_svs_exp)
                idx_met = find(~[app.struct_svs_exp.ref_bool]);
                for i = 1:length(idx_met)
                    app.struct_svs_exp(idx_met(i)).met_bool = false;
                end
                t_data = app.UITable_met_SVS.Data;
                t_data{:,1} = false(size(t_data,1),1);
                app.UITable_met_SVS.Data = t_data;
            end
        end

        % Button pushed function: SVSParametersButton
        function SVS_ParametersButtonPushed(app, event)
            app.ParametersSVSDWSApp = preferences_SVS_DWS(app,app.Spec_calc);
        end

        % Button pushed function: SVS_Choose_selection_Button
        function SVS_Choose_selection_ButtonPushed(app, event)
            if ~isempty(app.struct_svs_exp)
                idx_met_ref = find(or([app.struct_svs_exp.ref_bool],[app.struct_svs_exp.met_bool]));
                struct_svs_proc = app.struct_svs_exp(idx_met_ref);
                app.SVS_experiment = SVSClass(app.result_dir,app.study_name,struct_svs_proc, ...
                    app.svs_param);
                prog_dbox = uiprogressdlg(app.UIFigure,"Title",'Choose SVS selection', ...
                    "Message",'Creating RAW studies ...','ShowPercentage','on','Value',0);
                msg = app.SVS_experiment.create_Bruker_rawstudy(prog_dbox);
                if any(cellfun(@isempty,msg))
                    prog_dbox.Message = 'End creation of raw studies';
                    prog_dbox.Value = 1;
                    pause(1)
                    delete(prog_dbox);
                    app.SVS_Data_Processing_Button.Enable = 'on';
                    app.SVS_Quantification_Button.Enable = 'off';
                    app.SVS_TypeDropDown.Items = {'raw'};
                    app.SVS_ExperimentDropDown.Items = {app.SVS_experiment.SVS_struct.exp_name};
                    app.disp_SVS_check()
                    app.display_SVS_spectra()
                    app.SVS_quantif_bool = 0;
                    app.UITable_SVS_results.Data = [];
                else
                    delete(prog_dbox);
                    uialert(app.UIFigure,msg,'Load Bruker data')
                    app.SVS_Data_Processing_Button.Enable = 'off';
                    app.SVS_Quantification_Button.Enable = 'off';
                    app.SVS_TypeDropDown.Items = {'raw'};
                    app.SVS_ExperimentDropDown.Items = [];
                end
            end
            assignin("base",'test_SVS',app.SVS_experiment)
        end

        % Button pushed function: SVS_Data_Processing_Button
        function SVS_Data_Processing_ButtonPushed(app, event)
            prog_dbox = uiprogressdlg(app.UIFigure,"Title",'Data preprocessing', ...
                "Message",'Applying data preprocessing ...','ShowPercentage','on','Value',0);
            pause(1)
            msg = app.SVS_experiment.preprocessing_SVS_FidA(prog_dbox);
            if cellfun(@isempty,msg)
                prog_dbox.Message = 'Preproccesing succesfully applied';
                prog_dbox.Value = 1;
                pause(1.5)
                delete(prog_dbox);
                app.SVS_Quantification_Button.Enable = 'on';
                app.SVS_TypeDropDown.Items = {'raw','processed','processed + sum'};
                app.SVS_TypeDropDown.Value = 'processed + sum';
                app.SVS_ExperimentDropDown.Items = {app.SVS_experiment.SVS_struct.exp_name};
                app.disp_SVS_check()
                app.display_SVS_spectra()
                app.SVS_quantif_bool = 0;
                app.UITable_SVS_results.Data = [];
            else
                delete(prog_dbox)
                uialert(app.UIFigure,msg,'Apply preprocessing and summing')
                app.SVS_Quantification_Button.Enable = 'off';
                app.SVS_TypeDropDown.Items = {'raw'};
            end
            assignin("base",'test_SVS',app.SVS_experiment)
        end

        % Button pushed function: SVS_Quantification_Button
        function SVS_Quantification_ButtonPushed(app, event)
            prog_dbox = uiprogressdlg(app.UIFigure,"Title",'LCModel quantification', ...
                "Message",'Apply SVS quantification','Value',0);
            pause(1.5)
            msg = app.SVS_experiment.SVS_quantification(prog_dbox);
            if cellfun(@isempty,msg)
                prog_dbox.Message = 'SVS quantification succesfully runned';
                prog_dbox.Value = 1;
                pause(1)
                delete(prog_dbox);
                app.SVS_Quantification_Button.Enable = 'on';
                app.SVS_TypeDropDown.Items = {'raw','processed','processed + sum','quantified'};
                app.SVS_TypeDropDown.Value = 'quantified';
                exp_name = {app.SVS_experiment.SVS_struct.exp_name};
                idx_met = [app.SVS_experiment.SVS_struct.met_bool];
                app.SVS_ExperimentDropDown.Items = exp_name(idx_met);
                app.SVS_quantif_bool = 1;
                app.disp_SVS_check()
                % Display the spectra
                app.display_SVS_spectra()
                % Display results in table
                app.display_SVS_results()
            else
                delete(prog_dbox);
                uialert(app.UIFigure,msg,'Quantification with LCModel')
                app.SVS_Quantification_Button.Enable = 'off';
                app.SVS_quantif_bool = 0;
            end
            assignin("base",'test_SVS',app.SVS_experiment)
        end

        % Value changed function: SVS_ExperimentDropDown, SVS_TypeDropDown
        function SVS_Experiment_ValueChanged(app, event)
            app.disp_SVS_check()
            % Display the spectra
            app.display_SVS_spectra()
            % Display results in table
            app.display_SVS_results()
        end

        % Value changed function: SVS_absButton
        function SVS_absButtonValueChanged(app, event)
            app.SVS_realButton.Value = 0;
            app.SVS_imagButton.Value = 0;
            app.display_SVS_spectra()
        end

        % Value changed function: SVS_realButton
        function SVS_realButtonValueChanged(app, event)
            app.SVS_absButton.Value = 0;
            app.SVS_imagButton.Value = 0;
            app.display_SVS_spectra()
        end

        % Value changed function: SVS_imagButton
        function SVS_imagButtonValueChanged(app, event)
            app.SVS_absButton.Value = 0;
            app.SVS_realButton.Value = 0;
            app.display_SVS_spectra();
        end

        % Button pushed function: SVS_Next_spectrum_Button
        function SVS_Next_spectrum_ButtonPushed(app, event)
            app.SVS_Spectrum_nb_EditField.Value = app.SVS_Spectrum_nb_EditField.Value + 1;
            app.disp_SVS_check();
            app.display_SVS_spectra()
        end

        % Button pushed function: SVS_Previous_spectrum_Button
        function SVS_Previous_spectrum_ButtonPushed(app, event)
            app.SVS_Spectrum_nb_EditField.Value = app.SVS_Spectrum_nb_EditField.Value - 1;
            app.disp_SVS_check();
            app.display_SVS_spectra()
        end

        % Value changed function: SVS_Spectrum_nb_EditField
        function SVS_Spectrum_nb_EditFieldValueChanged(app, event)
            if ~isempty(app.SVS_experiment)
                app.disp_SVS_check();
                app.display_SVS_spectra()
            else
                app.SVS_Spectrum_nb_EditField.Value = 1;
            end
        end

        % Value changed function: SVS_BaselineButton, SVS_DataButton, 
        % ...and 1 other component
        function SVS_Quantif_display_ButtonValueChanged(app, event)
            if ~or(app.SVS_BaselineButton.Value,or(app.SVS_FitButton.Value,app.SVS_DataButton.Value))
                app.SVS_DataButton.Value = 1;
            end
            app.display_SVS_spectra()
        end

        % Button pushed function: DWS_result_folder_Button
        function DWS_result_folder_ButtonPushed(app, event)
            % Choose a directory for the result folder
            dir = uigetdir(app.result_dir,"Choose a directory");
            figure(app.UIFigure)
            if dir ~= 0
                if isfolder(dir)
                    app.result_dir = dir;
                end
                % Save the directory in the cookies
                temp = load(fullfile(app.app_path,'Others','Cookies.mat'),'T');
                T = temp.T;
                T.results_folder = dir;
                save(fullfile(app.app_path,'Others','Cookies.mat'),'T');
            end
        end

        % Button pushed function: DWS_Studyname_Button
        function DWS_Studyname_ButtonPushed(app, event)
            % Choose a Study name folder that already exist
            dir = uigetdir(app.result_dir,"Choose a directory");
            figure(app.UIFigure)
            if dir ~= 0
                if isfolder(dir)
                    [x,y,~] = fileparts(dir);
                    app.result_dir = x;
                    app.DWS_StudynameEditField.Value = y;
                    app.study_name = y;
                    app.DWS_check_study_folder();
                end
            end
        end

        % Value changed function: DWS_StudynameEditField
        function DWS_StudynameEditFieldValueChanged(app, event)
            app.study_name = app.DWS_StudynameEditField.Value;
            app.DWS_check_study_folder();
        end

        % Button pushed function: DWS_Load_data_Button
        function DWS_Load_data_ButtonPushed(app, event)
            if isempty(app.struct_dws_exp)
                app.struct_dws_exp = struct('folder',[],'expnb',[],'exp_name',[],'ref_bool',[],'met_bool',[]);
                app.struct_dws_exp(1) = [];
            end
            app.SVSDWSLoadApp = load_SVS_DWS(app);
        end

        % Value changed function: Ref_data_DWS_DropDown
        function DWS_Ref_data_DropDownValueChanged(app, event)
            % Update Ref Dropdown and bool
            value = app.Ref_data_DWS_DropDown.Value;
            expnames = {app.struct_dws_exp.exp_name};
            idx_ref = find(strcmp(expnames,value));
            idx_pre_ref = find([app.struct_dws_exp.ref_bool]);
            if ~isempty(idx_ref) 
                if ~isempty(idx_pre_ref)
                    app.struct_dws_exp(idx_pre_ref).ref_bool = false;
                end
                app.struct_dws_exp(idx_ref).ref_bool = true;
                app.struct_dws_exp(idx_ref).met_bool = false;
                % Update UITable
                idx_met = find(~[app.struct_dws_exp.ref_bool]);
                State = [app.struct_dws_exp.met_bool];
                table_met = table(State(idx_met).',expnames(idx_met).','VariableNames',{'On/Off','Experiment name'});
                app.UITable_met_DWS.Data = table_met;
            elseif ~isempty(idx_pre_ref)
                app.struct_dws_exp(idx_pre_ref).ref_bool = false;
                % Update UITable
                idx_met = find(~[app.struct_dws_exp.ref_bool]);
                State = [app.struct_dws_exp.met_bool];
                table_met = table(State(idx_met).',expnames(idx_met).','VariableNames',{'On/Off','Experiment name'});
                app.UITable_met_DWS.Data = table_met;
            end
        end

        % Cell edit callback: UITable_met_DWS
        function DWS_UITable_met_CellEdit(app, event)
            if ~isempty(app.struct_dws_exp)
                indices = event.Indices(1);
                t_data = app.UITable_met_DWS.Data{:,2};
                expname_met = t_data{indices(1)};
                idx_met_upd = find(strcmp({app.struct_dws_exp.exp_name},expname_met));
                app.struct_dws_exp(idx_met_upd).met_bool = not(app.struct_dws_exp(idx_met_upd).met_bool);
            end
        end

        % Button pushed function: DWS_Select_all_Button
        function DWS_Select_all_ButtonPushed(app, event)
            if ~isempty(app.struct_dws_exp)
                idx_met = find(~[app.struct_dws_exp.ref_bool]);
                for i = 1:length(idx_met)
                    app.struct_dws_exp(idx_met(i)).met_bool = true;
                end
                t_data = app.UITable_met_DWS.Data;
                t_data{:,1} = true(size(t_data,1),1);
                app.UITable_met_DWS.Data = t_data;
            end
        end

        % Button pushed function: DWS_Deselect_all_Button
        function DWS_Deselect_all_ButtonPushed(app, event)
            if ~isempty(app.struct_dws_exp)
                idx_met = find(~[app.struct_dws_exp.ref_bool]);
                for i = 1:length(idx_met)
                    app.struct_dws_exp(idx_met(i)).met_bool = false;
                end
                t_data = app.UITable_met_DWS.Data;
                t_data{:,1} = false(size(t_data,1),1);
                app.UITable_met_DWS.Data = t_data;
            end
        end

        % Button pushed function: DWS_ParametersButton
        function DWS_ParametersButtonPushed(app, event)
            app.ParametersSVSDWSApp = preferences_SVS_DWS(app,app.Spec_calc);
        end

        % Button pushed function: DWS_Choose_selection_Button
        function DWS_Choose_selection_ButtonPushed(app, event)
            if ~isempty(app.struct_dws_exp)
                idx_met_ref = find(or([app.struct_dws_exp.ref_bool],[app.struct_dws_exp.met_bool]));
                struct_dws_proc = app.struct_dws_exp(idx_met_ref);
                cla(app.UIAxes_DWS_fitting)
                app.DWS_fit_savefigure_Button.Enable = 'off';
                app.DWS_quantif_bool = 0;
                app.UITable_DWS_results.Data = [];
                app.DWS_experiment = DWSClass(app.result_dir,app.study_name,struct_dws_proc, ...
                    app.dws_param);
                prog_dbox = uiprogressdlg(app.UIFigure,"Title",'Choose DWS selection', ...
                    "Message",'Creating RAW studies ...','ShowPercentage','on','Value',0);
                msg = app.DWS_experiment.create_Bruker_rawstudy(prog_dbox);
                if any(cellfun(@isempty,msg))
                    prog_dbox.Message = 'End creation of raw studies';
                    prog_dbox.Value = 1;
                    pause(1)
                    delete(prog_dbox);
                    app.DWS_Data_Processing_Button.Enable = 'on';
                    app.DWS_Quantification_Button.Enable = 'off';
                    app.DWS_TypeDropDown.Items = {'raw'};
                    app.DWS_ExperimentDropDown.Items = {app.DWS_experiment.DWS_struct.exp_name};
                    app.disp_DWS_check()
                    app.display_DWS_spectra()
                else
                    delete(prog_dbox)
                    uialert(app.UIFigure,msg,'Load Bruker data')
                    app.DWS_Data_Processing_Button.Enable = 'off';
                    app.DWS_Quantification_Button.Enable = 'off';
                    app.DWS_TypeDropDown.Items = {'raw'};
                    app.DWS_ExperimentDropDown.Items = [];
                end
            end
            assignin("base",'test_DWS',app.DWS_experiment)
        end

        % Button pushed function: DWS_Data_Processing_Button
        function DWS_Data_Processing_ButtonPushed(app, event)
            cla(app.UIAxes_DWS_fitting)
            app.DWS_fit_savefigure_Button.Enable = 'off';
            app.DWS_quantif_bool = 0;
            app.UITable_DWS_results.Data = [];
            prog_dbox = uiprogressdlg(app.UIFigure,"Title",'Data preprocessing', ...
                "Message",'Applying data preprocessing ...','ShowPercentage','on','Value',0);
            pause(1)
            app.DWS_experiment.DWS_param = app.dws_param;
            msg = app.DWS_experiment.preprocessing_DWS_FidA(prog_dbox);
            if any(cellfun(@isempty,msg))
                prog_dbox.Message = 'Preproccesing succesfully applied';
                prog_dbox.Value = 1;
                pause(1.5)
                delete(prog_dbox);
                app.DWS_Quantification_Button.Enable = 'on';
                app.DWS_TypeDropDown.Items = {'raw','processed','processed + sum'};
                app.DWS_TypeDropDown.Value = 'processed + sum';
                app.DWS_ExperimentDropDown.Items = {app.DWS_experiment.DWS_struct.exp_name};
                app.disp_DWS_check()
                app.display_DWS_spectra()
            else
                delete(prog_dbox);
                uialert(app.UIFigure,msg,'Apply preprocessing and summing')
                app.DWS_Quantification_Button.Enable = 'off';
                app.DWS_TypeDropDown.Items = {'raw'};
            end
            assignin("base",'test_DWS',app.DWS_experiment)
        end

        % Button pushed function: DWS_Quantification_Button
        function DWS_Quantification_ButtonPushed(app, event)
            prog_dbox = uiprogressdlg(app.UIFigure,"Title",'LCModel quantification', ...
                "Message",'Apply DWS quantification','Value',0);
            pause(1.5)
            app.DWS_experiment.excluded_bval = app.DWS_exclude_bvalue_EditField.Value;
            app.DWS_experiment.radius_conv = app.DWS_rad_kurtosis_EditField.Value;
            app.DWS_experiment.DWS_param = app.dws_param;
            msg = app.DWS_experiment.DWS_quantification(prog_dbox);
            if any(cellfun(@isempty,msg))
                prog_dbox.Message = 'DWS quantification succesfully runned';
                prog_dbox.Value = 1;
                pause(1)
                delete(prog_dbox);
                app.DWS_Quantification_Button.Enable = 'on';
                app.DWS_TypeDropDown.Items = {'raw','processed','processed + sum','quantified'};
                app.DWS_TypeDropDown.Value = 'quantified';
                exp_name = {app.DWS_experiment.DWS_struct.exp_name};
                idx_met = [app.DWS_experiment.DWS_struct.met_bool];
                app.DWS_ExperimentDropDown.Items = exp_name(idx_met);
                app.DWS_quantif_bool = 1;
                app.disp_DWS_check()
                % Display the spectra
                app.display_DWS_spectra()
                % Display results in table
                app.display_DWS_results()
                app.DWS_fit_savefigure_Button.Enable = 'on';
                app.DWS_Metabolite_fit_DropDown.Items = app.DWS_experiment.Met_names;
                % Display fitting results
                app.plot_DWS_fitting()
            else
                delete(prog_dbox);
                uialert(app.UIFigure,msg,'Quantification with LCModel')
                app.DWS_Quantification_Button.Enable = 'off';
                app.DWS_ResultsDropDown.Enable = 'off';
                app.DWS_fit_savefigure_Button.Enable = 'off';
                app.DWS_quantif_bool = 0;
            end
            assignin("base",'test_DWS',app.DWS_experiment)
        end

        % Value changed function: DWS_ExperimentDropDown, DWS_TypeDropDown
        function DWS_Experiment_ValueChanged(app, event)
            app.disp_DWS_check()
            % Display the spectra
            app.display_DWS_spectra()
            % Display results in table
            app.display_DWS_results()
        end

        % Value changed function: DWS_absButton
        function DWS_absButtonValueChanged(app, event)
            app.DWS_realButton.Value = 0;
            app.DWS_imagButton.Value = 0;
            app.display_DWS_spectra()
        end

        % Value changed function: DWS_realButton
        function DWS_realButtonValueChanged(app, event)
            app.DWS_absButton.Value = 0;
            app.DWS_imagButton.Value = 0;
            app.display_DWS_spectra()
        end

        % Value changed function: DWS_imagButton
        function DWS_imagButtonValueChanged(app, event)
            app.DWS_absButton.Value = 0;
            app.DWS_realButton.Value = 0;
            app.display_DWS_spectra()
        end

        % Button pushed function: DWS_Next_spectrum_Button
        function DWS_Next_spectrum_ButtonPushed(app, event)
            app.DWS_Spectrum_nb_EditField.Value = app.DWS_Spectrum_nb_EditField.Value + 1;
            app.disp_DWS_check();
            app.display_DWS_spectra()
        end

        % Button pushed function: DWS_Previous_spectrum_Button
        function DWS_Previous_spectrum_ButtonPushed(app, event)
            app.DWS_Spectrum_nb_EditField.Value = app.DWS_Spectrum_nb_EditField.Value - 1;
            app.disp_DWS_check();
            app.display_DWS_spectra()
        end

        % Value changed function: DWS_Spectrum_nb_EditField
        function DWS_Spectrum_nb_EditFieldValueChanged(app, event)
            if ~isempty(app.DWS_experiment)
                app.disp_DWS_check();
                app.display_DWS_spectra()
            else
                app.DWS_Spectrum_nb_EditField.Value = 1;
            end
        end

        % Value changed function: DWS_BaselineButton, DWS_DataButton, 
        % ...and 1 other component
        function DWS_Quantif_display_ButtonValueChanged(app, event)
            if ~or(app.DWS_BaselineButton.Value,or(app.DWS_FitButton.Value,app.DWS_DataButton.Value))
                app.DWS_DataButton.Value = 1;
            end
            app.display_DWS_spectra()
        end

        % Value changed function: DWS_ResultsDropDown
        function DWS_ResultsDropDownValueChanged(app, event)
            if strcmp(app.DWS_ResultsDropDown.Value,'Concentrations')
                app.UIAxes_DWS_fitting.Visible = 'off';
                app.DWS_fitting_Panel.Visible = 'off';
                app.UITable_DWS_results.Visible = 'on';
                app.DWS_fit_savefigure_Button.Visible = 'off';
            else
                app.UIAxes_DWS_fitting.Visible = 'on';
                app.DWS_fitting_Panel.Visible = 'on';
                app.UITable_DWS_results.Visible = 'off';
                app.DWS_fit_savefigure_Button.Visible = 'on';
                if app.DWS_quantif_bool
                    app.plot_DWS_fitting()
                end
            end
        end

        % Value changed function: DWS_Metabolite_fit_DropDown
        function DWS_Metabolite_fit_DropDownValueChanged(app, event)
            if app.DWS_quantif_bool
                app.plot_DWS_fitting()
            end
        end

        % Button pushed function: DWS_fit_savefigure_Button
        function DWS_fit_savefigure_ButtonPushed(app, event)
            if app.DWS_quantif_bool
                Da_fit = app.DWS_experiment.Da_fit;
                adc = app.DWS_experiment.adc;
                akc = app.DWS_experiment.akc;
    
                idx_met = find(strcmp(app.DWS_experiment.Met_names,app.DWS_Metabolite_fit_DropDown.Value));
                
                if ~isnan(Da_fit(idx_met))
                    d = uiprogressdlg(app.UIFigure,"Title",'Save image','Message', ...
                        'Saving current displayed image in the study folder','Indeterminate','on');

                    % Oriented sticks model
                    pa_sticks = @(Da,b) sqrt(pi./(4*b.*Da)).*erf(sqrt(b.*Da));
                    % Kurtosis model
                    kurtosis = @(b,D,K) exp(-b.*D + 1/6.*b.^2*D.^2.*K);

                    rc = app.DWS_experiment.radius_conv; % radius of convergence of kurtosis model
                    excluded_bval = app.DWS_experiment.excluded_bval; % excluded points above this b-value

                    rel_conc_tot = app.DWS_experiment.abs_conc_tot;
                    std_rel_conc_tot = rel_conc_tot .* app.DWS_experiment.crlb_tot;

                    f = figure('Visible','off');
                    ax = axes(f);
                    b = app.DWS_experiment.b_value;
                    [b,idx_b] = sort(b);
                    index = b < excluded_bval;
                    Y = squeeze(rel_conc_tot(idx_met,:)) / squeeze(rel_conc_tot(idx_met,1));
                    Ysd = squeeze(std_rel_conc_tot(idx_met,:)) / squeeze(rel_conc_tot(idx_met,1));
                    Ysd = Y.* sqrt(Ysd.^2 + Ysd(1).^2);
                    Y = Y(idx_b); Ysd = Ysd(idx_b);
                    b = b(~isnan(Y)); Y_ = Y(~isnan(Y))'; Ysd = Ysd(~isnan(Ysd))';
                    hold(ax,'on')
                    % Data
                    errorbar(ax,b(index),Y_(index), ...
                        Ysd(index),'k.','MarkerSize',8,'LineWidth',1);
                    % Sticks
                    plot(ax,b(index),pa_sticks(Da_fit(idx_met),b(index)),'--', ...
                        'Color',[231,153,39]./256,'LineWidth',1.5);
                    index = b < rc;
                    % Kurtosis
                    plot(ax,b(index),kurtosis(b(index),adc(idx_met),akc(idx_met)),'--', ...
                        'Color',[117,197,155]./256,'LineWidth',1.5);
                    hold(ax,'off')
                    xlabel(ax,'b-value [ms/\mum^2]','Interpreter','tex')
                    ylabel(ax,'S/S_0','Interpreter','tex')
                    title(ax,'Diffusion fitting')

                    % Export image name and subtitle
                    folder_export = fullfile(app.result_dir,app.study_name,'Figures');
                    if ~isfolder(folder_export)
                        mkdir(folder_export);
                    end
                    savename = [app.DWS_Metabolite_fit_DropDown.Value, '_diffusion_fit'];

                    if isfile(fullfile(folder_export,[savename '.fig']))
                        n_fn = 2;
                        while isfile(fullfile(folder_export,[savename '_' num2str(n_fn) '.fig']))
                            n_fn = n_fn + 1;
                        end
                        savename = [savename '_' num2str(n_fn)];
                    end
                    filename = fullfile(folder_export,[savename,'.png']);
                    exportgraphics(f,filename,'Resolution',600);
                    filename = fullfile(folder_export,[savename,'.fig']);
                    set(f,'CreateFcn','set(gcbo,''Visible'',''on'')'); 
                    savefig(f,filename);
                    close(f)
                    figure(app.UIFigure)
                    close(d)
                end
            end
        end

        % Button pushed function: DWS_update_fitting_Button
        function DWS_update_fitting_ButtonPushed(app, event)
            if ~isempty(app.DWS_experiment)
                app.DWS_experiment.excluded_bval = app.DWS_exclude_bvalue_EditField.Value;
                app.DWS_experiment.radius_conv = app.DWS_rad_kurtosis_EditField.Value;
            end
            if app.DWS_quantif_bool
                app.DWS_experiment.DWS_fitting();
                app.plot_DWS_fitting()
            end
        end

        % Callback function
        function MRSI_Job0(app, event)
            value = app.MRSI_Job0CheckBox.Value;
            
        end

        % Value changed function: MRSI_Job0CheckBox
        function MRSI_Job0CheckBoxValueChanged(app, event)
            value = app.MRSI_Job0CheckBox.Value;
            % Change the displayed metabolite
            app.display_MRSI()
        end

        % Value changed function: MRSI_3D_CheckBox
        function MRSI_3D_CheckBoxValueChanged(app, event)
            value = app.MRSI_3D_CheckBox.Value;
            if value
                app.mrsi3d_bool = true;
            else
                app.mrsi3d_bool = false;
            end
        end

        % Button down function: UIAxes_MRSI_2
        function UIAxes_MRSI_2ButtonDown(app, event)
            
        end

        % Value changed function: MRSI_RecoNb_EditField
        function MRSI_RecoNb_EditFieldValueChanged(app, event)
            value = app.MRSI_RecoNb_EditField.Value;
            app.reco_fn = value;
        end

        % Value changed function: MRI_HeadProne_Box
        function MRI_HeadProne_BoxValueChanged(app, event)
            value = app.MRI_HeadProne_Box.Value;
            if value
                app.mri_headprone = true;
            else
                app.mri_headprone = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MRS4Brain Toolbox';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_square_2.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.Panel = uipanel(app.MenuTab);
            app.DWS_Button = uibutton(app.Panel, 'push');
            app.DWS_Button.ButtonPushedFcn = createCallbackFcn(app, @TabSelection_ButtonPushed, true);
            app.DWS_Button.Text = 'DWS';
            app.DWS_Label = uilabel(app.Panel);
            app.DWS_Label.Text = 'Diffusion weighted spectroscopy';
            app.SVS_Button = uibutton(app.Panel, 'push');
            app.SVS_Button.ButtonPushedFcn = createCallbackFcn(app, @TabSelection_ButtonPushed, true);
            app.SVS_Button.Text = 'SVS';
            app.SVS_Label = uilabel(app.Panel);
            app.SVS_Label.Text = 'Single voxel spectroscopy';
            app.MRSI_Button = uibutton(app.Panel, 'push');
            app.MRSI_Button.ButtonPushedFcn = createCallbackFcn(app, @TabSelection_ButtonPushed, true);
            app.MRSI_Button.Text = 'MRSI';
            app.MRSI_Label = uilabel(app.Panel);
            app.MRSI_Label.Text = 'Magnetic resonance spectroscopic imaging';
            app.App_logo_Image = uiimage(app.MenuTab);
            app.App_logo_Image.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_linear_2.png');
            app.Welcome_Label = uilabel(app.MenuTab);
            app.Welcome_Label.Text = 'Welcome to the MRS4Brain Toolbox, Please select one of the following spectroscopy modalities';
            app.CIBM_logo_Image = uiimage(app.MenuTab);
            app.CIBM_logo_Image.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'CIBM_logo_wo_bg_2.png');
            app.MRSITab = uitab(app.TabGroup);
            app.MRSITab.Title = 'MRSI';
            app.MRSI_Materials_Panel = uipanel(app.MRSITab);
            app.CIBM_Image_MRSI = uiimage(app.MRSI_Materials_Panel);
            app.CIBM_Image_MRSI.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'CIBM_logo_wo_bg_2.png');
            app.Toolbox_Image_MRSI = uiimage(app.MRSI_Materials_Panel);
            app.Toolbox_Image_MRSI.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_linear_2.png');
            app.MRSI_Data_management_Panel = uipanel(app.MRSI_Materials_Panel);
            app.StudynameEditFieldLabel = uilabel(app.MRSI_Data_management_Panel);
            app.StudynameEditFieldLabel.Text = 'Study name';
            app.MRSI_Studyname_EditField = uieditfield(app.MRSI_Data_management_Panel, 'text');
            app.MRSI_Studyname_EditField.ValueChangedFcn = createCallbackFcn(app, @MRSI_Studyname_EditFieldValueChanged, true);
            app.MRSI_Studyname_Button = uibutton(app.MRSI_Data_management_Panel, 'push');
            app.MRSI_Studyname_Button.ButtonPushedFcn = createCallbackFcn(app, @MRSI_Studyname_ButtonPushed, true);
            app.MRSI_Studyname_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'dir_study_name.png');
            app.MRSI_Studyname_Button.Text = '';
            app.MRSI_result_folder_Button = uibutton(app.MRSI_Data_management_Panel, 'push');
            app.MRSI_result_folder_Button.ButtonPushedFcn = createCallbackFcn(app, @MRSI_result_folder_ButtonPushed, true);
            app.MRSI_result_folder_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'dir_icon.png');
            app.MRSI_result_folder_Button.Text = ' Result folder';
            app.MRSI_Choose_path_Button = uibutton(app.MRSI_Data_management_Panel, 'push');
            app.MRSI_Choose_path_Button.ButtonPushedFcn = createCallbackFcn(app, @MRSI_Choose_path_ButtonPushed, true);
            app.MRSI_Choose_path_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'dir_icon.png');
            app.MRSI_Choose_path_Button.Text = 'Bruker data folder';
            app.MRSI_Data_management_Label = uilabel(app.MRSI_Materials_Panel);
            app.MRSI_Data_management_Label.Text = 'Data management';
            app.MRSI_Processings_Panel = uipanel(app.MRSI_Materials_Panel);
            app.NumberslicesSpinnerLabel = uilabel(app.MRSI_Processings_Panel);
            app.NumberslicesSpinnerLabel.Text = 'Number slices';
            app.DenoisingDropDownLabel = uilabel(app.MRSI_Processings_Panel);
            app.DenoisingDropDownLabel.Enable = 'off';
            app.DenoisingDropDownLabel.Text = 'Denoising';
            app.MRSI_Reconstruction_DropDown = uidropdown(app.MRSI_Processings_Panel);
            app.MRSI_Reconstruction_DropDown.Items = {'Cartesian', 'Compressed sensing', 'Concentred rings'};
            app.MRSI_Reconstruction_DropDown.Enable = 'off';
            app.MRSI_Reconstruction_DropDown.Value = 'Cartesian';
            app.MRSI_Denoising_DropDown = uidropdown(app.MRSI_Processings_Panel);
            app.MRSI_Denoising_DropDown.Items = {'None', 'MP-PCA', 'LR-TGV'};
            app.MRSI_Denoising_DropDown.Enable = 'off';
            app.MRSI_Denoising_DropDown.Value = 'None';
            app.MRSI_FillgapsButton = uibutton(app.MRSI_Processings_Panel, 'state');
            app.MRSI_FillgapsButton.ValueChangedFcn = createCallbackFcn(app, @MRSI_FillgapsButtonValueChanged, true);
            app.MRSI_FillgapsButton.Tooltip = {'Apply the Fillgaps method'};
            app.MRSI_FillgapsButton.Text = 'Fillgaps';
            app.MRSI_LipidsupButton = uibutton(app.MRSI_Processings_Panel, 'state');
            app.MRSI_LipidsupButton.Tooltip = {'Apply the lipid suppression method'};
            app.MRSI_LipidsupButton.Text = 'Lipid suppression';
            app.MRSI_Number_slices_Spinner = uispinner(app.MRSI_Processings_Panel);
            app.MRSI_Number_slices_Spinner.Limits = [1 Inf];
            app.MRSI_Number_slices_Spinner.RoundFractionalValues = 'on';
            app.MRSI_Number_slices_Spinner.ValueChangedFcn = createCallbackFcn(app, @MRSI_Number_slices_SpinnerValueChanged, true);
            app.MRSI_Number_slices_Spinner.Tooltip = {'Number of MRSI slices'};
            app.MRSI_Number_slices_Spinner.Value = 1;
            app.ReconstructionDropDownLabel = uilabel(app.MRSI_Processings_Panel);
            app.ReconstructionDropDownLabel.Enable = 'off';
            app.ReconstructionDropDownLabel.Text = 'Reconstruction';
            app.ReferenceLabel_2 = uilabel(app.MRSI_Processings_Panel);
            app.ReferenceLabel_2.Text = '3D MRSI';
            app.MRSI_3D_CheckBox = uicheckbox(app.MRSI_Processings_Panel);
            app.MRSI_3D_CheckBox.ValueChangedFcn = createCallbackFcn(app, @MRSI_3D_CheckBoxValueChanged, true);
            app.MRSI_3D_CheckBox.Text = '';
            app.MRSI_Processing_steps_Label = uilabel(app.MRSI_Materials_Panel);
            app.MRSI_Processing_steps_Label.Text = 'Processing steps';
            app.MRSI_Analysis_Button = uibutton(app.MRSI_Materials_Panel, 'push');
            app.MRSI_Analysis_Button.ButtonPushedFcn = createCallbackFcn(app, @MRSI_Analysis_ButtonPushed, true);
            app.MRSI_Analysis_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'MRSI_Analysis.png');
            app.MRSI_Analysis_Button.Text = '';
            app.MRSI_Data_specific_Panel = uipanel(app.MRSI_Materials_Panel);
            app.MRSI_MRISlice_UITable = uitable(app.MRSI_Data_specific_Panel);
            app.MRSI_MRISlice_UITable.ColumnName = {'MRI Slice'; 'Number'};
            app.MRSI_MRISlice_UITable.RowName = {};
            app.MRSI_MRISlice_UITable.SelectionType = 'row';
            app.MRSI_MRISlice_UITable.ColumnEditable = [false true];
            app.MRSI_MRISlice_UITable.CellEditCallback = createCallbackFcn(app, @MRSI_Data_specific_ValueChanged, true);
            app.MRSI_MRISlice_UITable.Multiselect = 'off';
            app.MRSI_Slice_range_EditField = uieditfield(app.MRSI_Data_specific_Panel, 'numeric');
            app.MRSI_Slice_range_EditField.Limits = [1 Inf];
            app.MRSI_Slice_range_EditField.RoundFractionalValues = 'on';
            app.MRSI_Slice_range_EditField.ValueDisplayFormat = '%.0f';
            app.MRSI_Slice_range_EditField.ValueChangedFcn = createCallbackFcn(app, @MRSI_Data_specific_ValueChanged, true);
            app.MRSI_Slice_range_EditField.Value = 1;
            app.ImageEditFieldLabel = uilabel(app.MRSI_Data_specific_Panel);
            app.ImageEditFieldLabel.Text = 'Image';
            app.MRSI_Image_EditField = uieditfield(app.MRSI_Data_specific_Panel, 'numeric');
            app.MRSI_Image_EditField.Limits = [1 Inf];
            app.MRSI_Image_EditField.RoundFractionalValues = 'on';
            app.MRSI_Image_EditField.ValueDisplayFormat = '%.0f';
            app.MRSI_Image_EditField.ValueChangedFcn = createCallbackFcn(app, @MRSI_Data_specific_ValueChanged, true);
            app.MRSI_Image_EditField.Value = 12;
            app.MRSI_Ref_EditField = uieditfield(app.MRSI_Data_specific_Panel, 'numeric');
            app.MRSI_Ref_EditField.Limits = [1 Inf];
            app.MRSI_Ref_EditField.RoundFractionalValues = 'on';
            app.MRSI_Ref_EditField.ValueDisplayFormat = '%.0f';
            app.MRSI_Ref_EditField.ValueChangedFcn = createCallbackFcn(app, @MRSI_Data_specific_ValueChanged, true);
            app.MRSI_Ref_EditField.Value = 21;
            app.MetabolitesEditFieldLabel = uilabel(app.MRSI_Data_specific_Panel);
            app.MetabolitesEditFieldLabel.Text = 'Metabolites';
            app.MRSI_Met_EditField = uieditfield(app.MRSI_Data_specific_Panel, 'numeric');
            app.MRSI_Met_EditField.Limits = [1 Inf];
            app.MRSI_Met_EditField.RoundFractionalValues = 'on';
            app.MRSI_Met_EditField.ValueDisplayFormat = '%.0f';
            app.MRSI_Met_EditField.ValueChangedFcn = createCallbackFcn(app, @MRSI_Data_specific_ValueChanged, true);
            app.MRSI_Met_EditField.Value = 20;
            app.ReferenceLabel = uilabel(app.MRSI_Data_specific_Panel);
            app.ReferenceLabel.Text = 'Ref';
            app.MRSI_exp_nb_folder_Label = uilabel(app.MRSI_Data_specific_Panel);
            app.MRSI_exp_nb_folder_Label.Text = 'Experiment number folder';
            app.SlicerangeLabel = uilabel(app.MRSI_Data_specific_Panel);
            app.SlicerangeLabel.Text = {'Slice'; 'range'};
            app.MRSI_Ref_CheckBox = uicheckbox(app.MRSI_Data_specific_Panel);
            app.MRSI_Ref_CheckBox.ValueChangedFcn = createCallbackFcn(app, @MRSI_Ref_CheckBoxValueChanged, true);
            app.MRSI_Ref_CheckBox.Text = '';
            app.MRSI_Ref_CheckBox.Value = true;
            app.RecoNbLabel = uilabel(app.MRSI_Data_specific_Panel);
            app.RecoNbLabel.Text = 'Reco. Nb';
            app.MRSI_RecoNb_EditField = uieditfield(app.MRSI_Data_specific_Panel, 'numeric');
            app.MRSI_RecoNb_EditField.Limits = [1 Inf];
            app.MRSI_RecoNb_EditField.RoundFractionalValues = 'on';
            app.MRSI_RecoNb_EditField.ValueDisplayFormat = '%.0f';
            app.MRSI_RecoNb_EditField.ValueChangedFcn = createCallbackFcn(app, @MRSI_RecoNb_EditFieldValueChanged, true);
            app.MRSI_RecoNb_EditField.Value = 1;
            app.MRSI_Open_pdfdoc_Button = uibutton(app.MRSI_Data_specific_Panel, 'push');
            app.MRSI_Open_pdfdoc_Button.ButtonPushedFcn = createCallbackFcn(app, @MRSI_Open_pdfdoc_ButtonPushed, true);
            app.MRSI_Open_pdfdoc_Button.Enable = 'off';
            app.MRSI_Open_pdfdoc_Button.Tooltip = {'Open PDF document with the experiment processes'};
            app.MRSI_Open_pdfdoc_Button.Text = 'PDF document';
            app.ReferenceLabel_3 = uilabel(app.MRSI_Data_specific_Panel);
            app.ReferenceLabel_3.Text = '9.4T (for MRI)';
            app.MRI_HeadProne_Box = uicheckbox(app.MRSI_Data_specific_Panel);
            app.MRI_HeadProne_Box.ValueChangedFcn = createCallbackFcn(app, @MRI_HeadProne_BoxValueChanged, true);
            app.MRI_HeadProne_Box.Text = '';
            app.MRSI_Data_specific_Label = uilabel(app.MRSI_Materials_Panel);
            app.MRSI_Data_specific_Label.Text = 'Data specific';
            app.MRSI_Options_Panel = uipanel(app.MRSI_Materials_Panel);
            app.MRSI_Preferences_Button = uibutton(app.MRSI_Options_Panel, 'push');
            app.MRSI_Preferences_Button.ButtonPushedFcn = createCallbackFcn(app, @MRSI_Preferences_ButtonPushed, true);
            app.MRSI_Preferences_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'preferences_icon.png');
            app.MRSI_Preferences_Button.Tooltip = {'Preferences (MRSI, registration and LCModel)'};
            app.MRSI_Preferences_Button.Text = '';
            app.MRSI_Statistics_Button = uibutton(app.MRSI_Options_Panel, 'push');
            app.MRSI_Statistics_Button.ButtonPushedFcn = createCallbackFcn(app, @MRSI_Statistics_ButtonPushed, true);
            app.MRSI_Statistics_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Stats.png');
            app.MRSI_Statistics_Button.Tooltip = {'Statistics tool'};
            app.MRSI_Statistics_Button.Text = '';
            app.MRSI_OptionsLabel = uilabel(app.MRSI_Materials_Panel);
            app.MRSI_OptionsLabel.Text = 'Options';
            app.MRSI_Display_results_Panel = uipanel(app.MRSITab);
            app.UIAxes_MRSI_1 = uiaxes(app.MRSI_Display_results_Panel);
            zlabel(app.UIAxes_MRSI_1, 'Z')
            app.UIAxes_MRSI_1.XTick = [];
            app.UIAxes_MRSI_1.YTick = [];
            colormap(app.UIAxes_MRSI_1, 'gray')
            app.UIAxes_MRSI_2 = uiaxes(app.MRSI_Display_results_Panel);
            zlabel(app.UIAxes_MRSI_2, 'Z')
            app.UIAxes_MRSI_2.XTick = [];
            app.UIAxes_MRSI_2.YTick = [];
            app.UIAxes_MRSI_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_MRSI_2ButtonDown, true);
            app.MRSI_Show_Results_Panel = uipanel(app.MRSI_Display_results_Panel);
            app.RegionDropDownLabel = uilabel(app.MRSI_Show_Results_Panel);
            app.RegionDropDownLabel.Text = 'Region';
            app.MetaboliteDropDownLabel = uilabel(app.MRSI_Show_Results_Panel);
            app.MetaboliteDropDownLabel.Text = 'Metabolite';
            app.SliceDropDownLabel = uilabel(app.MRSI_Show_Results_Panel);
            app.SliceDropDownLabel.Text = 'Slice';
            app.MRSI_Volumetry_Button = uibutton(app.MRSI_Show_Results_Panel, 'push');
            app.MRSI_Volumetry_Button.ButtonPushedFcn = createCallbackFcn(app, @MRSI_Volumetry_ButtonPushed, true);
            app.MRSI_Volumetry_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'brain_volumetry.png');
            app.MRSI_Volumetry_Button.Enable = 'off';
            app.MRSI_Volumetry_Button.Tooltip = {'Volumetry of the brain regions segmented'};
            app.MRSI_Volumetry_Button.Text = '';
            app.MRSI_Spectra_Button = uibutton(app.MRSI_Show_Results_Panel, 'push');
            app.MRSI_Spectra_Button.ButtonPushedFcn = createCallbackFcn(app, @MRSI_Spectra_ButtonPushed, true);
            app.MRSI_Spectra_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'spectra_MRSI.png');
            app.MRSI_Spectra_Button.Enable = 'off';
            app.MRSI_Spectra_Button.Tooltip = {'Volumetry of the brain regions segmented'};
            app.MRSI_Spectra_Button.Text = '';
            app.MRSI_Save_map_Button = uibutton(app.MRSI_Show_Results_Panel, 'push');
            app.MRSI_Save_map_Button.ButtonPushedFcn = createCallbackFcn(app, @MRSI_Save_map_ButtonPushed, true);
            app.MRSI_Save_map_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'save_map.png');
            app.MRSI_Save_map_Button.Enable = 'off';
            app.MRSI_Save_map_Button.Tooltip = {'Save the current metabolite map with the anatomical image in background'};
            app.MRSI_Save_map_Button.Text = '';
            app.MRSI_OverlapSlider = uislider(app.MRSI_Show_Results_Panel);
            app.MRSI_OverlapSlider.Limits = [0 1];
            app.MRSI_OverlapSlider.ValueChangedFcn = createCallbackFcn(app, @MRSI_OverlapSliderValueChanged, true);
            app.MRSI_OverlapSlider.Value = 0.8;
            app.MRSI_conc_table_Button = uibutton(app.MRSI_Show_Results_Panel, 'push');
            app.MRSI_conc_table_Button.ButtonPushedFcn = createCallbackFcn(app, @MRSI_conc_table_ButtonPushed, true);
            app.MRSI_conc_table_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'concentration_table.png');
            app.MRSI_conc_table_Button.Enable = 'off';
            app.MRSI_conc_table_Button.Tooltip = {'Display the mean metabolite concentration for the current brain mask '};
            app.MRSI_conc_table_Button.Text = '';
            app.MRSI_Slice_DropDown = uidropdown(app.MRSI_Show_Results_Panel);
            app.MRSI_Slice_DropDown.Items = {'1'};
            app.MRSI_Slice_DropDown.ValueChangedFcn = createCallbackFcn(app, @MRSI_Slice_DropDownValueChanged, true);
            app.MRSI_Slice_DropDown.Value = '1';
            app.MRSI_MetaboliteDropDown = uidropdown(app.MRSI_Show_Results_Panel);
            app.MRSI_MetaboliteDropDown.Items = {'NAA'};
            app.MRSI_MetaboliteDropDown.ValueChangedFcn = createCallbackFcn(app, @MRSI_MetaboliteDropDownValueChanged, true);
            app.MRSI_MetaboliteDropDown.Enable = 'off';
            app.MRSI_MetaboliteDropDown.Value = 'NAA';
            app.MRSI_Voxel_selection_Button = uibutton(app.MRSI_Show_Results_Panel, 'push');
            app.MRSI_Voxel_selection_Button.ButtonPushedFcn = createCallbackFcn(app, @MRSI_Voxel_selection_ButtonPushed, true);
            app.MRSI_Voxel_selection_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'voxel_selection_new.png');
            app.MRSI_Voxel_selection_Button.Enable = 'off';
            app.MRSI_Voxel_selection_Button.Tooltip = {'Manual selection of MRSI voxels'};
            app.MRSI_Voxel_selection_Button.Text = '';
            app.SideDropDownLabel = uilabel(app.MRSI_Show_Results_Panel);
            app.SideDropDownLabel.Text = 'Side';
            app.MRSI_SideDropDown = uidropdown(app.MRSI_Show_Results_Panel);
            app.MRSI_SideDropDown.Items = {'Left', 'Right', 'L+R'};
            app.MRSI_SideDropDown.ValueChangedFcn = createCallbackFcn(app, @MRSI_Region_Side_ValueChanged, true);
            app.MRSI_SideDropDown.Enable = 'off';
            app.MRSI_SideDropDown.Value = 'L+R';
            app.MRSI_RegionDropDown = uidropdown(app.MRSI_Show_Results_Panel);
            app.MRSI_RegionDropDown.Items = {'Whole Brain'};
            app.MRSI_RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @MRSI_Region_Side_ValueChanged, true);
            app.MRSI_RegionDropDown.Enable = 'off';
            app.MRSI_RegionDropDown.Value = 'Whole Brain';
            app.OverlapSliderLabel = uilabel(app.MRSI_Show_Results_Panel);
            app.OverlapSliderLabel.Text = 'Overlap';
            app.MRSI_CustomregionsCheckBox = uicheckbox(app.MRSI_Show_Results_Panel);
            app.MRSI_CustomregionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MRSI_CustomregionsCheckBoxValueChanged, true);
            app.MRSI_CustomregionsCheckBox.Enable = 'off';
            app.MRSI_CustomregionsCheckBox.Text = 'Custom regions';
            app.ConcLabel = uilabel(app.MRSI_Show_Results_Panel);
            app.ConcLabel.Text = 'Concentrations';
            app.MRSI_ConcentrationsDropDown = uidropdown(app.MRSI_Show_Results_Panel);
            app.MRSI_ConcentrationsDropDown.Items = {'Relative', 'Absolute', 'CRLB'};
            app.MRSI_ConcentrationsDropDown.ValueChangedFcn = createCallbackFcn(app, @MRSI_ConcentrationsDropDownValueChanged, true);
            app.MRSI_ConcentrationsDropDown.Value = 'Relative';
            app.MRSI_CustomButton = uibutton(app.MRSI_Show_Results_Panel, 'push');
            app.MRSI_CustomButton.ButtonPushedFcn = createCallbackFcn(app, @MRSI_CustomButtonPushed, true);
            app.MRSI_CustomButton.Visible = 'off';
            app.MRSI_CustomButton.Text = 'Custom';
            app.MRSI_Overlap_EditField = uieditfield(app.MRSI_Show_Results_Panel, 'numeric');
            app.MRSI_Overlap_EditField.Limits = [0 1];
            app.MRSI_Overlap_EditField.ValueDisplayFormat = '%11.2g';
            app.MRSI_Overlap_EditField.ValueChangedFcn = createCallbackFcn(app, @MRSI_Overlap_EditFieldValueChanged, true);
            app.MRSI_Overlap_EditField.Value = 0.8;
            app.MRSI_Display_additional_Button = uibutton(app.MRSI_Show_Results_Panel, 'push');
            app.MRSI_Display_additional_Button.ButtonPushedFcn = createCallbackFcn(app, @MRSI_Display_additional_ButtonPushed, true);
            app.MRSI_Display_additional_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'gears.png');
            app.MRSI_Display_additional_Button.Tooltip = {'Display preferences (quality control, maximum concentration)'};
            app.MRSI_Display_additional_Button.Text = '';
            app.MRSI_Job0CheckBox = uicheckbox(app.MRSI_Show_Results_Panel);
            app.MRSI_Job0CheckBox.ValueChangedFcn = createCallbackFcn(app, @MRSI_Job0CheckBoxValueChanged, true);
            app.MRSI_Job0CheckBox.Enable = 'off';
            app.MRSI_Job0CheckBox.Text = 'Job0 Recon.';
            app.MRSI_Show_Results_Label = uilabel(app.MRSI_Display_results_Panel);
            app.MRSI_Show_Results_Label.Text = 'Show Results';
            app.SVSTab = uitab(app.TabGroup);
            app.SVSTab.Title = 'SVS';
            app.Materials_Panel_SVS = uipanel(app.SVSTab);
            app.SVS_Quantification_Button = uibutton(app.Materials_Panel_SVS, 'push');
            app.SVS_Quantification_Button.ButtonPushedFcn = createCallbackFcn(app, @SVS_Quantification_ButtonPushed, true);
            app.SVS_Quantification_Button.Enable = 'off';
            app.SVS_Quantification_Button.Text = 'Quantification';
            app.SVS_Data_Processing_Button = uibutton(app.Materials_Panel_SVS, 'push');
            app.SVS_Data_Processing_Button.ButtonPushedFcn = createCallbackFcn(app, @SVS_Data_Processing_ButtonPushed, true);
            app.SVS_Data_Processing_Button.Enable = 'off';
            app.SVS_Data_Processing_Button.Text = 'Data Processing';
            app.SVS_Experiments_Panel = uipanel(app.Materials_Panel_SVS);
            app.ReferencedataLabel = uilabel(app.SVS_Experiments_Panel);
            app.ReferencedataLabel.Text = 'Reference data';
            app.SVS_Choose_selection_Button = uibutton(app.SVS_Experiments_Panel, 'push');
            app.SVS_Choose_selection_Button.ButtonPushedFcn = createCallbackFcn(app, @SVS_Choose_selection_ButtonPushed, true);
            app.SVS_Choose_selection_Button.Text = 'Choose selection';
            app.SVS_Deselect_all_Button = uibutton(app.SVS_Experiments_Panel, 'push');
            app.SVS_Deselect_all_Button.ButtonPushedFcn = createCallbackFcn(app, @SVS_Deselect_all_ButtonPushed, true);
            app.SVS_Deselect_all_Button.Text = 'Deselect all';
            app.SVS_Select_all_Button = uibutton(app.SVS_Experiments_Panel, 'push');
            app.SVS_Select_all_Button.ButtonPushedFcn = createCallbackFcn(app, @SVS_Select_all_ButtonPushed, true);
            app.SVS_Select_all_Button.Text = 'Select all';
            app.UITable_met_SVS = uitable(app.SVS_Experiments_Panel);
            app.UITable_met_SVS.ColumnName = {'On/Off'; 'Experiment'};
            app.UITable_met_SVS.RowName = {};
            app.UITable_met_SVS.ColumnEditable = [true false];
            app.UITable_met_SVS.CellEditCallback = createCallbackFcn(app, @SVS_UITable_met_CellEdit, true);
            app.Ref_data_SVS_DropDown = uidropdown(app.SVS_Experiments_Panel);
            app.Ref_data_SVS_DropDown.Items = {'None'};
            app.Ref_data_SVS_DropDown.ValueChangedFcn = createCallbackFcn(app, @SVS_Ref_data_DropDownValueChanged, true);
            app.Ref_data_SVS_DropDown.Value = 'None';
            app.SVS_Metabolite_data_Label = uilabel(app.SVS_Experiments_Panel);
            app.SVS_Metabolite_data_Label.Text = 'Metabolite data';
            app.SVS_Experiments_Label = uilabel(app.Materials_Panel_SVS);
            app.SVS_Experiments_Label.Text = 'Experiments';
            app.SVS_Data_management_Panel = uipanel(app.Materials_Panel_SVS);
            app.StudynameEditField_2Label = uilabel(app.SVS_Data_management_Panel);
            app.StudynameEditField_2Label.Text = 'Study name';
            app.SVS_StudynameEditField = uieditfield(app.SVS_Data_management_Panel, 'text');
            app.SVS_StudynameEditField.ValueChangedFcn = createCallbackFcn(app, @SVS_StudynameEditFieldValueChanged, true);
            app.SVS_Studyname_Button = uibutton(app.SVS_Data_management_Panel, 'push');
            app.SVS_Studyname_Button.ButtonPushedFcn = createCallbackFcn(app, @SVS_Studyname_ButtonPushed, true);
            app.SVS_Studyname_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'dir_study_name.png');
            app.SVS_Studyname_Button.Text = '';
            app.SVS_result_folder_Button = uibutton(app.SVS_Data_management_Panel, 'push');
            app.SVS_result_folder_Button.ButtonPushedFcn = createCallbackFcn(app, @SVS_result_folder_ButtonPushed, true);
            app.SVS_result_folder_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'dir_icon.png');
            app.SVS_result_folder_Button.Text = ' Result folder';
            app.Load_SVS_data_Button = uibutton(app.SVS_Data_management_Panel, 'push');
            app.Load_SVS_data_Button.ButtonPushedFcn = createCallbackFcn(app, @SVS_Load_data_ButtonPushed, true);
            app.Load_SVS_data_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'load.png');
            app.Load_SVS_data_Button.Text = 'Load SVS data';
            app.SVS_Data_management_Label = uilabel(app.Materials_Panel_SVS);
            app.SVS_Data_management_Label.Text = 'Data management';
            app.Toolbox_Image_SVS = uiimage(app.Materials_Panel_SVS);
            app.Toolbox_Image_SVS.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_linear_2.png');
            app.CIBM_Image_SVS = uiimage(app.Materials_Panel_SVS);
            app.CIBM_Image_SVS.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'CIBM_logo_wo_bg_2.png');
            app.SVSParametersButton = uibutton(app.Materials_Panel_SVS, 'push');
            app.SVSParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SVS_ParametersButtonPushed, true);
            app.SVSParametersButton.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'preferences_icon.png');
            app.SVSParametersButton.Text = 'SVS Parameters';
            app.Display_results_Panel_SVS = uipanel(app.SVSTab);
            app.UIAxes_SVS = uiaxes(app.Display_results_Panel_SVS);
            xlabel(app.UIAxes_SVS, 'ppm scale')
            app.UIAxes_SVS.XLim = [0 5];
            app.UIAxes_SVS.XDir = 'reverse';
            app.ExperimentDropDownLabel = uilabel(app.Display_results_Panel_SVS);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.TypeDropDownLabel = uilabel(app.Display_results_Panel_SVS);
            app.TypeDropDownLabel.Text = 'Type';
            app.UITable_SVS_results = uitable(app.Display_results_Panel_SVS);
            app.UITable_SVS_results.ColumnName = {'Metabolite'; 'Relative conc'; 'Absolute conc'; 'CRLB [%]'};
            app.UITable_SVS_results.RowName = {};
            app.SVS_BaselineButton = uibutton(app.Display_results_Panel_SVS, 'state');
            app.SVS_BaselineButton.ValueChangedFcn = createCallbackFcn(app, @SVS_Quantif_display_ButtonValueChanged, true);
            app.SVS_BaselineButton.Visible = 'off';
            app.SVS_BaselineButton.Text = 'Baseline';
            app.SVS_FitButton = uibutton(app.Display_results_Panel_SVS, 'state');
            app.SVS_FitButton.ValueChangedFcn = createCallbackFcn(app, @SVS_Quantif_display_ButtonValueChanged, true);
            app.SVS_FitButton.Visible = 'off';
            app.SVS_FitButton.Text = 'Fit';
            app.SVS_FitButton.Value = true;
            app.SVS_DataButton = uibutton(app.Display_results_Panel_SVS, 'state');
            app.SVS_DataButton.ValueChangedFcn = createCallbackFcn(app, @SVS_Quantif_display_ButtonValueChanged, true);
            app.SVS_DataButton.Visible = 'off';
            app.SVS_DataButton.Text = 'Data';
            app.SVS_DataButton.Value = true;
            app.SVS_imagButton = uibutton(app.Display_results_Panel_SVS, 'state');
            app.SVS_imagButton.ValueChangedFcn = createCallbackFcn(app, @SVS_imagButtonValueChanged, true);
            app.SVS_imagButton.Text = 'imag';
            app.SVS_realButton = uibutton(app.Display_results_Panel_SVS, 'state');
            app.SVS_realButton.ValueChangedFcn = createCallbackFcn(app, @SVS_realButtonValueChanged, true);
            app.SVS_realButton.Text = 'real';
            app.SVS_absButton = uibutton(app.Display_results_Panel_SVS, 'state');
            app.SVS_absButton.ValueChangedFcn = createCallbackFcn(app, @SVS_absButtonValueChanged, true);
            app.SVS_absButton.Text = 'abs';
            app.SVS_absButton.Value = true;
            app.SVS_TypeDropDown = uidropdown(app.Display_results_Panel_SVS);
            app.SVS_TypeDropDown.Items = {'raw'};
            app.SVS_TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SVS_Experiment_ValueChanged, true);
            app.SVS_TypeDropDown.Value = 'raw';
            app.SVS_ExperimentDropDown = uidropdown(app.Display_results_Panel_SVS);
            app.SVS_ExperimentDropDown.Items = {};
            app.SVS_ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @SVS_Experiment_ValueChanged, true);
            app.SVS_ExperimentDropDown.Value = {};
            app.SVS_Spectrum_nb_EditField = uieditfield(app.Display_results_Panel_SVS, 'numeric');
            app.SVS_Spectrum_nb_EditField.Limits = [1 Inf];
            app.SVS_Spectrum_nb_EditField.ValueChangedFcn = createCallbackFcn(app, @SVS_Spectrum_nb_EditFieldValueChanged, true);
            app.SVS_Spectrum_nb_EditField.Value = 1;
            app.SVS_Next_spectrum_Button = uibutton(app.Display_results_Panel_SVS, 'push');
            app.SVS_Next_spectrum_Button.ButtonPushedFcn = createCallbackFcn(app, @SVS_Next_spectrum_ButtonPushed, true);
            app.SVS_Next_spectrum_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'right_arrow.png');
            app.SVS_Next_spectrum_Button.Enable = 'off';
            app.SVS_Next_spectrum_Button.Text = '';
            app.SVS_Previous_spectrum_Button = uibutton(app.Display_results_Panel_SVS, 'push');
            app.SVS_Previous_spectrum_Button.ButtonPushedFcn = createCallbackFcn(app, @SVS_Previous_spectrum_ButtonPushed, true);
            app.SVS_Previous_spectrum_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'left_arrow.png');
            app.SVS_Previous_spectrum_Button.Enable = 'off';
            app.SVS_Previous_spectrum_Button.Text = '';
            app.DWSTab = uitab(app.TabGroup);
            app.DWSTab.Title = 'DWS';
            app.DWS_Materials_Panel = uipanel(app.DWSTab);
            app.DWS_Quantification_Button = uibutton(app.DWS_Materials_Panel, 'push');
            app.DWS_Quantification_Button.ButtonPushedFcn = createCallbackFcn(app, @DWS_Quantification_ButtonPushed, true);
            app.DWS_Quantification_Button.Enable = 'off';
            app.DWS_Quantification_Button.Text = 'Quantification';
            app.DWS_Data_Processing_Button = uibutton(app.DWS_Materials_Panel, 'push');
            app.DWS_Data_Processing_Button.ButtonPushedFcn = createCallbackFcn(app, @DWS_Data_Processing_ButtonPushed, true);
            app.DWS_Data_Processing_Button.Enable = 'off';
            app.DWS_Data_Processing_Button.Text = 'Data Processing';
            app.DWS_Experiments_Panel = uipanel(app.DWS_Materials_Panel);
            app.ReferencedataLabel_2 = uilabel(app.DWS_Experiments_Panel);
            app.ReferencedataLabel_2.Text = 'Reference data';
            app.Ref_data_DWS_DropDown = uidropdown(app.DWS_Experiments_Panel);
            app.Ref_data_DWS_DropDown.Items = {'None'};
            app.Ref_data_DWS_DropDown.ValueChangedFcn = createCallbackFcn(app, @DWS_Ref_data_DropDownValueChanged, true);
            app.Ref_data_DWS_DropDown.Value = 'None';
            app.DWS_Choose_selection_Button = uibutton(app.DWS_Experiments_Panel, 'push');
            app.DWS_Choose_selection_Button.ButtonPushedFcn = createCallbackFcn(app, @DWS_Choose_selection_ButtonPushed, true);
            app.DWS_Choose_selection_Button.Text = 'Choose selection';
            app.DWS_Deselect_all_Button = uibutton(app.DWS_Experiments_Panel, 'push');
            app.DWS_Deselect_all_Button.ButtonPushedFcn = createCallbackFcn(app, @DWS_Deselect_all_ButtonPushed, true);
            app.DWS_Deselect_all_Button.Text = 'Deselect all';
            app.DWS_Select_all_Button = uibutton(app.DWS_Experiments_Panel, 'push');
            app.DWS_Select_all_Button.ButtonPushedFcn = createCallbackFcn(app, @DWS_Select_all_ButtonPushed, true);
            app.DWS_Select_all_Button.Text = 'Select all';
            app.UITable_met_DWS = uitable(app.DWS_Experiments_Panel);
            app.UITable_met_DWS.ColumnName = {'On/Off'; 'Experiment'};
            app.UITable_met_DWS.RowName = {};
            app.UITable_met_DWS.ColumnEditable = [true false];
            app.UITable_met_DWS.CellEditCallback = createCallbackFcn(app, @DWS_UITable_met_CellEdit, true);
            app.DWS_Metabolite_data_Label = uilabel(app.DWS_Experiments_Panel);
            app.DWS_Metabolite_data_Label.Text = 'Metabolite data';
            app.DWS_Data_management_Panel = uipanel(app.DWS_Materials_Panel);
            app.StudynameEditField_2Label_2 = uilabel(app.DWS_Data_management_Panel);
            app.StudynameEditField_2Label_2.Text = 'Study name';
            app.DWS_StudynameEditField = uieditfield(app.DWS_Data_management_Panel, 'text');
            app.DWS_StudynameEditField.ValueChangedFcn = createCallbackFcn(app, @DWS_StudynameEditFieldValueChanged, true);
            app.DWS_Studyname_Button = uibutton(app.DWS_Data_management_Panel, 'push');
            app.DWS_Studyname_Button.ButtonPushedFcn = createCallbackFcn(app, @DWS_Studyname_ButtonPushed, true);
            app.DWS_Studyname_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'dir_study_name.png');
            app.DWS_Studyname_Button.Text = '';
            app.DWS_result_folder_Button = uibutton(app.DWS_Data_management_Panel, 'push');
            app.DWS_result_folder_Button.ButtonPushedFcn = createCallbackFcn(app, @DWS_result_folder_ButtonPushed, true);
            app.DWS_result_folder_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'dir_icon.png');
            app.DWS_result_folder_Button.Text = ' Result folder';
            app.DWS_Load_data_Button = uibutton(app.DWS_Data_management_Panel, 'push');
            app.DWS_Load_data_Button.ButtonPushedFcn = createCallbackFcn(app, @DWS_Load_data_ButtonPushed, true);
            app.DWS_Load_data_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'load.png');
            app.DWS_Load_data_Button.Text = 'Load DWS data';
            app.DWS_Data_management_Label = uilabel(app.DWS_Materials_Panel);
            app.DWS_Data_management_Label.Text = 'Data management';
            app.Toolbox_Image_DWS = uiimage(app.DWS_Materials_Panel);
            app.Toolbox_Image_DWS.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_linear_2.png');
            app.CIBM_Image_DWS = uiimage(app.DWS_Materials_Panel);
            app.CIBM_Image_DWS.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'CIBM_logo_wo_bg_2.png');
            app.DWS_Experiments_Label = uilabel(app.DWS_Materials_Panel);
            app.DWS_Experiments_Label.Text = 'Experiments';
            app.DWS_ParametersButton = uibutton(app.DWS_Materials_Panel, 'push');
            app.DWS_ParametersButton.ButtonPushedFcn = createCallbackFcn(app, @DWS_ParametersButtonPushed, true);
            app.DWS_ParametersButton.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'preferences_icon.png');
            app.DWS_ParametersButton.Text = 'DWS Parameters';
            app.DWS_Display_results_Pane = uipanel(app.DWSTab);
            app.UIAxes_DWS = uiaxes(app.DWS_Display_results_Pane);
            xlabel(app.UIAxes_DWS, 'ppm scale')
            app.UIAxes_DWS.XLim = [0 5];
            app.UIAxes_DWS.XDir = 'reverse';
            app.UIAxes_DWS_fitting = uiaxes(app.DWS_Display_results_Pane);
            title(app.UIAxes_DWS_fitting, 'Diffusion fitting')
            xlabel(app.UIAxes_DWS_fitting, 'b-value [ms/\mum^2]')
            ylabel(app.UIAxes_DWS_fitting, 'S/S_0')
            zlabel(app.UIAxes_DWS_fitting, 'Z')
            app.ExperimentDropDownLabel_2 = uilabel(app.DWS_Display_results_Pane);
            app.ExperimentDropDownLabel_2.Text = 'Experiment';
            app.DWS_ExperimentDropDown = uidropdown(app.DWS_Display_results_Pane);
            app.DWS_ExperimentDropDown.Items = {};
            app.DWS_ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @DWS_Experiment_ValueChanged, true);
            app.DWS_ExperimentDropDown.Value = {};
            app.TypeDropDownLabel_2 = uilabel(app.DWS_Display_results_Pane);
            app.TypeDropDownLabel_2.Text = 'Type';
            app.DWS_TypeDropDown = uidropdown(app.DWS_Display_results_Pane);
            app.DWS_TypeDropDown.Items = {'raw'};
            app.DWS_TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DWS_Experiment_ValueChanged, true);
            app.DWS_TypeDropDown.Value = 'raw';
            app.UITable_DWS_results = uitable(app.DWS_Display_results_Pane);
            app.UITable_DWS_results.ColumnName = {'Metabolite'; 'Relative conc'; 'Absolute conc'; 'CRLB [%]'};
            app.UITable_DWS_results.RowName = {};
            app.UITable_DWS_results.Visible = 'off';
            app.DWS_fitting_Panel = uipanel(app.DWS_Display_results_Pane);
            app.D_intraLabel = uilabel(app.DWS_fitting_Panel);
            app.D_intraLabel.Text = 'D_{intra} [\mum^2/ms]';
            app.Da_fit_EditField = uieditfield(app.DWS_fitting_Panel, 'numeric');
            app.Da_fit_EditField.ValueDisplayFormat = '%5.2f';
            app.Da_fit_EditField.Editable = 'off';
            app.Da_fit_EditField.Tooltip = {'Diffusion coefficient from Oriented sticks modelling'};
            app.ADCLabel = uilabel(app.DWS_fitting_Panel);
            app.ADCLabel.Text = 'ADC [\mum^2/ms]';
            app.ADC_EditField = uieditfield(app.DWS_fitting_Panel, 'numeric');
            app.ADC_EditField.ValueDisplayFormat = '%5.2f';
            app.ADC_EditField.Editable = 'off';
            app.ADC_EditField.Tooltip = {'Apparent Diffusion Coefficient - Kurtosis model'};
            app.AKCLabel = uilabel(app.DWS_fitting_Panel);
            app.AKCLabel.Text = 'AKC [a.u.]';
            app.AKC_EditField = uieditfield(app.DWS_fitting_Panel, 'numeric');
            app.AKC_EditField.ValueDisplayFormat = '%5.2f';
            app.AKC_EditField.Editable = 'off';
            app.AKC_EditField.Tooltip = {'Apparent Kurtosis Coefficient - Kurtosis model'};
            app.MetaboliteDropDown_2Label = uilabel(app.DWS_fitting_Panel);
            app.MetaboliteDropDown_2Label.Text = 'Metabolite';
            app.DWS_Metabolite_fit_DropDown = uidropdown(app.DWS_fitting_Panel);
            app.DWS_Metabolite_fit_DropDown.Items = {'-'};
            app.DWS_Metabolite_fit_DropDown.ValueChangedFcn = createCallbackFcn(app, @DWS_Metabolite_fit_DropDownValueChanged, true);
            app.DWS_Metabolite_fit_DropDown.Value = '-';
            app.ExcludedbvalueEditFieldLabel = uilabel(app.DWS_fitting_Panel);
            app.ExcludedbvalueEditFieldLabel.Text = 'Excluded b-value';
            app.DWS_exclude_bvalue_EditField = uieditfield(app.DWS_fitting_Panel, 'numeric');
            app.DWS_exclude_bvalue_EditField.Limits = [0 Inf];
            app.DWS_exclude_bvalue_EditField.Value = 20;
            app.RadiusKurtosisEditFieldLabel = uilabel(app.DWS_fitting_Panel);
            app.RadiusKurtosisEditFieldLabel.Text = 'Radius Kurtosis';
            app.DWS_rad_kurtosis_EditField = uieditfield(app.DWS_fitting_Panel, 'numeric');
            app.DWS_rad_kurtosis_EditField.Limits = [0 Inf];
            app.DWS_rad_kurtosis_EditField.Value = 15;
            app.DWS_update_fitting_Button = uibutton(app.DWS_fitting_Panel, 'push');
            app.DWS_update_fitting_Button.ButtonPushedFcn = createCallbackFcn(app, @DWS_update_fitting_ButtonPushed, true);
            app.DWS_update_fitting_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'update.png');
            app.DWS_update_fitting_Button.Text = {'Update'; 'fitting'};
            app.DWS_BaselineButton = uibutton(app.DWS_Display_results_Pane, 'state');
            app.DWS_BaselineButton.ValueChangedFcn = createCallbackFcn(app, @DWS_Quantif_display_ButtonValueChanged, true);
            app.DWS_BaselineButton.Visible = 'off';
            app.DWS_BaselineButton.Text = 'Baseline';
            app.DWS_FitButton = uibutton(app.DWS_Display_results_Pane, 'state');
            app.DWS_FitButton.ValueChangedFcn = createCallbackFcn(app, @DWS_Quantif_display_ButtonValueChanged, true);
            app.DWS_FitButton.Visible = 'off';
            app.DWS_FitButton.Text = 'Fit';
            app.DWS_FitButton.Value = true;
            app.DWS_DataButton = uibutton(app.DWS_Display_results_Pane, 'state');
            app.DWS_DataButton.ValueChangedFcn = createCallbackFcn(app, @DWS_Quantif_display_ButtonValueChanged, true);
            app.DWS_DataButton.Visible = 'off';
            app.DWS_DataButton.Text = 'Data';
            app.DWS_DataButton.Value = true;
            app.DWS_imagButton = uibutton(app.DWS_Display_results_Pane, 'state');
            app.DWS_imagButton.ValueChangedFcn = createCallbackFcn(app, @DWS_imagButtonValueChanged, true);
            app.DWS_imagButton.Text = 'imag';
            app.DWS_realButton = uibutton(app.DWS_Display_results_Pane, 'state');
            app.DWS_realButton.ValueChangedFcn = createCallbackFcn(app, @DWS_realButtonValueChanged, true);
            app.DWS_realButton.Text = 'real';
            app.DWS_absButton = uibutton(app.DWS_Display_results_Pane, 'state');
            app.DWS_absButton.ValueChangedFcn = createCallbackFcn(app, @DWS_absButtonValueChanged, true);
            app.DWS_absButton.Text = 'abs';
            app.DWS_absButton.Value = true;
            app.DWS_Spectrum_nb_EditField = uieditfield(app.DWS_Display_results_Pane, 'numeric');
            app.DWS_Spectrum_nb_EditField.Limits = [1 Inf];
            app.DWS_Spectrum_nb_EditField.ValueChangedFcn = createCallbackFcn(app, @DWS_Spectrum_nb_EditFieldValueChanged, true);
            app.DWS_Spectrum_nb_EditField.Value = 1;
            app.DWS_Next_spectrum_Button = uibutton(app.DWS_Display_results_Pane, 'push');
            app.DWS_Next_spectrum_Button.ButtonPushedFcn = createCallbackFcn(app, @DWS_Next_spectrum_ButtonPushed, true);
            app.DWS_Next_spectrum_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'right_arrow.png');
            app.DWS_Next_spectrum_Button.Enable = 'off';
            app.DWS_Next_spectrum_Button.Text = '';
            app.DWS_Previous_spectrum_Button = uibutton(app.DWS_Display_results_Pane, 'push');
            app.DWS_Previous_spectrum_Button.ButtonPushedFcn = createCallbackFcn(app, @DWS_Previous_spectrum_ButtonPushed, true);
            app.DWS_Previous_spectrum_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'left_arrow.png');
            app.DWS_Previous_spectrum_Button.Enable = 'off';
            app.DWS_Previous_spectrum_Button.Text = '';
            app.ResultsDropDownLabel = uilabel(app.DWS_Display_results_Pane);
            app.ResultsDropDownLabel.Text = 'Results';
            app.DWS_ResultsDropDown = uidropdown(app.DWS_Display_results_Pane);
            app.DWS_ResultsDropDown.Items = {'Fitting', 'Concentrations'};
            app.DWS_ResultsDropDown.ValueChangedFcn = createCallbackFcn(app, @DWS_ResultsDropDownValueChanged, true);
            app.DWS_ResultsDropDown.Value = 'Fitting';
            app.DWS_fit_savefigure_Button = uibutton(app.DWS_Display_results_Pane, 'push');
            app.DWS_fit_savefigure_Button.ButtonPushedFcn = createCallbackFcn(app, @DWS_fit_savefigure_ButtonPushed, true);
            app.DWS_fit_savefigure_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'save.png');
            app.DWS_fit_savefigure_Button.Enable = 'off';
            app.DWS_fit_savefigure_Button.Text = 'Save figure';
            app.DWS_spectra_savefigure_Button = uibutton(app.DWS_Display_results_Pane, 'push');
            app.DWS_spectra_savefigure_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'save.png');
            app.DWS_spectra_savefigure_Button.Visible = 'off';
            app.DWS_spectra_savefigure_Button.Text = 'Save figure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRS4BRAIN_toolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end